classdef EDAAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        axesMain                        matlab.ui.control.UIAxes
        labelFileName                   matlab.ui.control.Label
        labelTrialNumber                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ParametersforEDAAnalysisDerivationMethodTab  matlab.ui.container.Tab
        EnablestartandendtimeforalltrialsCheckBox  matlab.ui.control.CheckBox
        MinwidthinsecondstodefinestartEditField_2Label  matlab.ui.control.Label
        MinwidthinsecondstodefinestartEditField  matlab.ui.control.NumericEditField
        MinheightinStodefinestartEditFieldLabel  matlab.ui.control.Label
        MinheightinStodefinestartEditField  matlab.ui.control.NumericEditField
        MinpeakwidthinsecondsEditField_2Label  matlab.ui.control.Label
        MinpeakwidthinsecondsEditField  matlab.ui.control.NumericEditField
        StartoftimewindowinsecondsEditField_2Label  matlab.ui.control.Label
        StartoftimewindowinsecondsEditField  matlab.ui.control.NumericEditField
        EndoftimewindowinsecondsEditField_2Label  matlab.ui.control.Label
        EndoftimewindowinsecondsEditField  matlab.ui.control.NumericEditField
        CommentsTextAreaLabel           matlab.ui.control.Label
        CommentsTextArea                matlab.ui.control.TextArea
        ParametersforEDAAnalysisPeaksMethodTab  matlab.ui.container.Tab
        EnablestartandendtimeforalltrialsCheckBox_2  matlab.ui.control.CheckBox
        MinwidthinsecondstodefinestartEditField_2Label_2  matlab.ui.control.Label
        MinwidthinsecondstodefinestartEditField_2  matlab.ui.control.NumericEditField
        MinheightinStodefinestartEditField_2Label  matlab.ui.control.Label
        MinheightinStodefinestartEditField_2  matlab.ui.control.NumericEditField
        MinpeakwidthinsecondsEditField_2Label_2  matlab.ui.control.Label
        MinpeakwidthinsecondsEditField_2  matlab.ui.control.NumericEditField
        StartoftimewindowinsecondsEditField_2Label_2  matlab.ui.control.Label
        StartoftimewindowinsecondsEditField_2  matlab.ui.control.NumericEditField
        EndoftimewindowinsecondsEditField_2Label_2  matlab.ui.control.Label
        EndoftimewindowinsecondsEditField_2  matlab.ui.control.NumericEditField
        PleasechangethesesettingsintheDerivationMethodtabifneededLabel  matlab.ui.control.Label
        EnableProminenceCheckBox        matlab.ui.control.CheckBox
        ProminenceMinLabel              matlab.ui.control.Label
        ProminenceMinEditField          matlab.ui.control.NumericEditField
        ProminenceMaxLabel              matlab.ui.control.Label
        ProminenceMaxEditField          matlab.ui.control.NumericEditField
        LicenseAcknowledgmentTab        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        globalStartTime_Label           matlab.ui.control.Label
        globalStopTime_label            matlab.ui.control.Label
        RecomputeEDA_Button             matlab.ui.control.Button
        yAxisPlus_Button                matlab.ui.control.Button
        yAxisMinus_Button               matlab.ui.control.Button
        ChangescaleofYaxisLabel         matlab.ui.control.Label
        ComputedEDAPanel                matlab.ui.container.Panel
        DeleteselectedButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        Label_2                         matlab.ui.control.Label
        MergewithpreviousButton         matlab.ui.control.Button
        SplitselectedButton             matlab.ui.control.Button
        SplitSelectedDropDown           matlab.ui.control.DropDown
        PreviousTrialButton             matlab.ui.control.Button
        NextTrialButton                 matlab.ui.control.Button
        VersionNumber                   matlab.ui.control.Label
        FilehandlingPanel               matlab.ui.container.Panel
        buttonLoadRawData               matlab.ui.control.Button
        LoadEDAdataButton               matlab.ui.control.Button
        SaveEDAButton                   matlab.ui.control.Button
        ExportEDAExcelMATLABButton      matlab.ui.control.Button
        Export2BIDSButton               matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        wwwcogpsyrubdeLabel             matlab.ui.control.Label
    end


    properties (Access = public)
        EDAANALYSIS % Description
    end


    methods (Access = private)


        function initEDAData(app)
            % This function is used to initialize the edaData struct/property

            % Tobias Otto
            % 1.1
            % 19.12.2021

            % 10.12.2020, Tobias: code adapted from EDA-Analyses 2 to work with the new AppDesigner
            % 19.12.2021, Tobias: added bids struct for export

            %% Init variables
            app.EDAANALYSIS.configFileName          = '';
            app.EDAANALYSIS.eda                     = [];
            app.EDAANALYSIS.edaAdjusted             = '';
            app.EDAANALYSIS.edaData                 = {};
            app.EDAANALYSIS.edaFileName             = '';
            app.EDAANALYSIS.edaFilePath             = '';
            app.EDAANALYSIS.edaMaskWindow           = [];
            app.EDAANALYSIS.edaMaxData              = [];
            app.EDAANALYSIS.edaMaxTime              = [];
            app.EDAANALYSIS.edaMaxVal               = [];
            app.EDAANALYSIS.edaMinData              = [];
            app.EDAANALYSIS.edaMinTime              = [];
            app.EDAANALYSIS.edaRes                  = struct;
            app.EDAANALYSIS.edaTime                 = {};
            app.EDAANALYSIS.edaTimeRes              = [];
            app.EDAANALYSIS.filterNumerator         = [];
            app.EDAANALYSIS.globalTrialStartTime    = [];
            app.EDAANALYSIS.globalTrialStopTime     = [];
            app.EDAANALYSIS.i                       = 1;
            app.EDAANALYSIS.numTrials               = [];
            app.EDAANALYSIS.sPos                    = struct;
            app.EDAANALYSIS.sPosTime                = [];
            app.EDAANALYSIS.sPosTimeStimOnset       = [];
            app.EDAANALYSIS.sampRate                = [];
            app.EDAANALYSIS.startTime               = [];
            app.EDAANALYSIS.stimOnset               = [];
            app.EDAANALYSIS.stimPresTime            = [];
            app.EDAANALYSIS.stopTime                = [];
            app.EDAANALYSIS.timeWindowStart         = [];
            app.EDAANALYSIS.timeWindowStop          = [];
            app.EDAANALYSIS.trialData               = {};
            app.EDAANALYSIS.trialSep                = [];
            app.EDAANALYSIS.trialStart              = [];
            app.EDAANALYSIS.trialStop               = [];
            app.EDAANALYSIS.trialTime               = {};
            app.EDAANALYSIS.xlabel                  = '';
            app.EDAANALYSIS.yLimFactor              = [0.8 1.2];
            app.EDAANALYSIS.yRangeMarkerFactor      = [];
            app.EDAANALYSIS.ylabel                  = '';
            app.EDAANALYSIS.comment                 = {''};
            app.EDAANALYSIS.splitSelectedEDAVersion = 'new';
            app.EDAANALYSIS.bids                    = [];
        end

        function computeData2Plot(app)
            % Computes everything that is needed to plot the raw EDA trial based

            % Tobias Otto
            % 1.1
            % 10.02.2021

            % 23.11.2020, Tobias: code adapted from EDA-Analyses 2 to work with the new AppDesigner
            % 10.02.2021, Tobias: removed plotting here --> call plotEDAData

            %% Init variables
            i               = app.EDAANALYSIS.i;
            data            = app.EDAANALYSIS.eda;
            sampRate        = app.EDAANALYSIS.sampRate;

            if(~isempty(app.EDAANALYSIS.trialSep))
                startTime   = app.EDAANALYSIS.trialSep(i);                  % Trial start time
                stopTime    = app.EDAANALYSIS.trialSep(i+1);                % Trial stop time
            else
                startTime   = app.EDAANALYSIS.trialStart(i);
                stopTime    = app.EDAANALYSIS.trialStop(i);
            end
            stimTime    = app.EDAANALYSIS.stimOnset(i)-startTime;   	% Stimulus onset

            % Time in seconds that has to be added at the end. (Data will be deleted in computeEDA)
            filtError = length(app.EDAANALYSIS.filterNumerator)/sampRate;

            %% Get data for trial
            if(startTime == 0)
                % Let's set start time to 1/samplingrate to get the first sample
                startTime = 1/sampRate;
            end

            % Get data for special positions to plot
            if(~isempty(app.EDAANALYSIS.sPos))
                for j=1:length(app.EDAANALYSIS.sPos)
                    % Start time for special position (e.g. stimulus onset, answering
                    % time) with reference to trial start!
                    sPosTime(j)	= app.EDAANALYSIS.sPos{j}.time(i)-startTime;

                    % Start time for special marker with reference to stimulus onset
                    % This value is needed for export data to Excel, because all EDA
                    % time stamps are with reference to stimulus onset!
                    sPosTimeStimOnset(j) = app.EDAANALYSIS.sPos{j}.time(i)-stimTime-startTime;
                end
            else
                sPosTime            = -99;
                sPosTimeStimOnset   = -99;
            end

            %% Get data for EDA Analysis
            % Get data for trial
            trialData   = data(round(startTime*sampRate) : round(stopTime*sampRate));
            % Compute time (from 0 to defined time)
            trialTime   = linspace(0,stopTime-startTime, length(trialData));

            % Get data for EDA analysis
            % Maximum value on y axis that is needed to set a EDA as valid
            % (even though the text in the app says min height to start
            % EDA)
            edaMaxVal      = app.MinheightinStodefinestartEditField.Value;
            % Masking time that is needed to compute "Sattelpunk"
            % (even though the text in the app is min width to start EDA)
            edaMaskWindow   = app.MinwidthinsecondstodefinestartEditField.Value;

            % Get stop time for time window
            timeWinStart    = app.StartoftimewindowinsecondsEditField.Value;
            timeWinStop     = app.EndoftimewindowinsecondsEditField.Value;

            % Time window can't be too long, otherwise it's longer than the trial
            % itself. Check and correct if necessary
            if(timeWinStop > (stopTime-startTime-stimTime))
                timeWinStop                                 = (stopTime-startTime-stimTime);
                app.EndoftimewindowinsecondsEditField.Value = timeWinStop;
            end

            % Given time in seconds after stimulus onset
            edaData     = data(round((startTime+stimTime+timeWinStart)*sampRate) : round((startTime+stimTime+timeWinStop)*sampRate));    % Compute eda from Stimulus presentation until the end
            edaTime     = linspace(stimTime+timeWinStart,stimTime+timeWinStop, length(edaData));
            edaDataWork = data(round((startTime+stimTime+timeWinStart)*sampRate) : round((startTime+stimTime+timeWinStop+filtError)*sampRate));

            %% Filter and compute data
            app.computeEDA(edaDataWork, stimTime);

            %% Save to result struct
            app.EDAANALYSIS.edaData{i}              = edaData;
            app.EDAANALYSIS.edaTime{i}              = edaTime;
            app.EDAANALYSIS.trialData{i}            = trialData;
            app.EDAANALYSIS.trialTime{i}            = trialTime;
            app.EDAANALYSIS.stimPresTime(i)         = stimTime;
            app.EDAANALYSIS.sPosTime(i,:)           = sPosTime;
            app.EDAANALYSIS.sPosTimeStimOnset(i,:)  = sPosTimeStimOnset;
            app.EDAANALYSIS.stopTime(i)             = stopTime;
            app.EDAANALYSIS.startTime(i)            = startTime;
            app.EDAANALYSIS.timeWindowStart(i)      = timeWinStart;
            app.EDAANALYSIS.timeWindowStop(i)       = timeWinStop;
            app.EDAANALYSIS.edaMaxVal(i)            = edaMaxVal;
            app.EDAANALYSIS.edaMaskWindow(i)        = edaMaskWindow;
            app.EDAANALYSIS.globalTrialStartTime(i)	= startTime;%(cutEDAANALYSIS of whole experiment)
            app.EDAANALYSIS.globalTrialStopTime(i)  = stopTime;%(cutout of whole experiment)

            %% Plot data
            %app.plotEDAData; do this in plotEDA data
        end

        function plotEDAData(app)
            % This function plots the previously computed data

            % Tobias Otto
            % 1.5
            % 22.06.2021

            % 11.12.2020, Tobias: code adapted from EDA-Analyses 2 to work with the new AppDesigner
            % 17.12.2020, Tobias: optimized zoom in and out
            % 09.02.2021, Tobias: Applying settings from loaded EDA resul files
            % 01.03.2021, Tobias: added mure user info (trial time and global time)
            % 09.04.2021, Tobias: display correct for completely negative EDA
            % 22.06.2021, Tobias: added µS to listbox as unit; removed tex in line labeling

            %% Init variables
            i           = app.EDAANALYSIS.i;
            trialData   = app.EDAANALYSIS.trialData{i};
            trialTime   = app.EDAANALYSIS.trialTime{i};
            edaTime     = app.EDAANALYSIS.edaTime{i};
            edaData     = app.EDAANALYSIS.edaData{i};
            edaMinTime  = app.EDAANALYSIS.edaRes(i).minTime;
            edaMaxTime  = app.EDAANALYSIS.edaRes(i).maxTime;
            edaMinDat   = app.EDAANALYSIS.edaRes(i).minData;
            edaMaxDat   = app.EDAANALYSIS.edaRes(i).maxData;
            stimTime    = app.EDAANALYSIS.stimPresTime(i);
            useDeriv    = app.EDAANALYSIS.edaRes(i).useDerivation;
            useFP       = app.EDAANALYSIS.edaRes(i).useFindPeaks;
            useProm     = app.EDAANALYSIS.edaRes(i).useProm;

            % Compute y range for markers
            minDat          = min(trialData);
            maxDat          = max(trialData);

            % Correct yLimFactor in case it has changed (for really old data)
            if(app.EDAANALYSIS.yLimFactor(1) > app.EDAANALYSIS.yLimFactor(2))
                tmp1 = app.EDAANALYSIS.yLimFactor(1);
                tmp2 = app.EDAANALYSIS.yLimFactor(2);

                app.EDAANALYSIS.yLimFactor(1) = tmp2;
                app.EDAANALYSIS.yLimFactor(2) = tmp1;
            end

            % Correct markers (sign change)
            if(minDat < 0 && maxDat > 0)
                yLimFactor1 = app.EDAANALYSIS.yLimFactor(2);
                yLimFactor2 = app.EDAANALYSIS.yLimFactor(2);
            elseif(minDat < 0 && maxDat < 0)
                yLimFactor1 = app.EDAANALYSIS.yLimFactor(2);
                yLimFactor2 = app.EDAANALYSIS.yLimFactor(1);
            else
                yLimFactor1 = app.EDAANALYSIS.yLimFactor(1);
                yLimFactor2 = app.EDAANALYSIS.yLimFactor(2);
            end

            % Compute YLim
            % Check for really small data and compensate for that
            if((maxDat-minDat) < 0.3)
                yLimFactor1 = yLimFactor1-0.1;
                yLimFactor2 = yLimFactor2+0.1;
            end

            YLim            = [minDat*yLimFactor1 maxDat*yLimFactor2];

            % Init text andn marker size
            yDist           = diff(YLim)/50;    % Used for text in optional Markers
            evYPos          = yDist*0.5;        % Used for text in optional Markers
            yDist2          = diff(YLim)/250;   % Used for all markers
            yRangeMarker    = YLim.*[1+yDist2 1-yDist2];    % Used for all markers

            if(~isempty(app.EDAANALYSIS.trialSep))
                maxTrials = length(app.EDAANALYSIS.trialSep)-1;
            elseif(~isempty(app.EDAANALYSIS.trialStart))
                maxTrials = length(app.EDAANALYSIS.trialStart);
            end

            %% Plot
            % Plot complete trial
            plot(app.axesMain, trialTime, trialData);
            hold(app.axesMain, 'on');

            % Plot only data used to compute EDA
            plot(app.axesMain, edaTime, edaData,'r')

            % Info test for user
            app.labelTrialNumber.Text   = ['Trial: ' num2str(i) ' of ' num2str(maxTrials) '  '];
            app.axesMain.XLabel.String  = 'Trial time in s';
            app.axesMain.YLabel.String  = app.EDAANALYSIS.ylabel;

            % Stimulus is plotted in red
            line(app.axesMain, [stimTime stimTime], yRangeMarker, 'Color', 'r', 'LineWidth', 2);   % Plot stimulus
            text(app.axesMain, stimTime*1.01, yRangeMarker(2), ['Stimulus | ' num2str(stimTime) 's'], 'color','r', 'Interpreter', 'none');

            %% Set global time entries
            app.globalStartTime_Label.Text  = sprintf('%.2fs (global start time)', app.EDAANALYSIS.globalTrialStartTime(i));
            app.globalStopTime_label.Text   = sprintf('(global stop time) %.2fs ', app.EDAANALYSIS.globalTrialStopTime(i));

            %% Set entries for EDA parameters
            app.MinheightinStodefinestartEditField.Value        = app.EDAANALYSIS.edaMaxVal(i); % Maximum value on y axis that is needed to set a EDA as valid
            app.MinheightinStodefinestartEditField_2.Value      = app.EDAANALYSIS.edaMaxVal(i); % Maximum value on y axis that is needed to set a EDA as valid
            app.MinwidthinsecondstodefinestartEditField.Value   = app.EDAANALYSIS.edaMaskWindow(i); % Masking time that is needed to compute "Sattelpunk"
            app.MinwidthinsecondstodefinestartEditField_2.Value = app.EDAANALYSIS.edaMaskWindow(i); % Masking time that is needed to compute "Sattelpunk"
            app.ProminenceMaxEditField.Value                    = app.EDAANALYSIS.edaRes(i).promMax;
            app.ProminenceMinEditField.Value                    = app.EDAANALYSIS.edaRes(i).promMin;
            app.EnableProminenceCheckBox.Value                  = logical(app.EDAANALYSIS.edaRes(i).useProm);
            app.MinpeakwidthinsecondsEditField.Value            = app.EDAANALYSIS.edaRes(i).minPeakWidth;
            app.MinpeakwidthinsecondsEditField_2.Value          = app.EDAANALYSIS.edaRes(i).minPeakWidth;
            app.MinheightinStodefinestartEditField.Value        = app.EDAANALYSIS.edaRes(i).minYVal;
            app.MinheightinStodefinestartEditField_2.Value      = app.EDAANALYSIS.edaRes(i).minYVal;
            app.StartoftimewindowinsecondsEditField.Value       = app.EDAANALYSIS.edaRes(i).timeWinStart;
            app.StartoftimewindowinsecondsEditField_2.Value     = app.EDAANALYSIS.edaRes(i).timeWinStart;
            app.EndoftimewindowinsecondsEditField.Value         = app.EDAANALYSIS.timeWindowStop(i);
            app.EndoftimewindowinsecondsEditField_2.Value       = app.EDAANALYSIS.timeWindowStop(i);

            if(useDeriv == 1)
                % Select correct tab
                app.TabGroup.SelectedTab = app.ParametersforEDAAnalysisDerivationMethodTab;

                % Disable prominance fields in case they were enabled by
                % already computed data
                app.ProminenceMaxLabel.Enable       = false;
                app.ProminenceMaxEditField.Enable   = false;
                app.ProminenceMaxEditField.Editable = false;
                app.ProminenceMinLabel.Enable       = false;
                app.ProminenceMinEditField.Enable   = false;
                app.ProminenceMinEditField.Editable = false;
                app.EnableProminenceCheckBox.Value  = false;
            elseif(useFP == 1)
                % Select correct tab
                app.TabGroup.SelectedTab = app.ParametersforEDAAnalysisPeaksMethodTab;

                % Enable prominance fields (disabled by default)
                if(useProm == 1)
                    app.ProminenceMaxLabel.Enable       = true;
                    app.ProminenceMaxEditField.Enable   = true;
                    app.ProminenceMaxEditField.Editable = true;
                    app.ProminenceMinLabel.Enable       = true;
                    app.ProminenceMinEditField.Enable   = true;
                    app.ProminenceMinEditField.Editable = true;
                end
            end

            %% Plot box for each result
            if(~isempty(edaMinTime))
                for j=1:length(edaMinTime)
                    line(app.axesMain, [edaMinTime(j) edaMinTime(j)], [edaMinDat(j) edaMaxDat(j)], 'Color', 'k', 'LineWidth', 1);     % Plot answer
                    line(app.axesMain, [edaMaxTime(j) edaMaxTime(j)], [edaMinDat(j) edaMaxDat(j)], 'Color', 'k', 'LineWidth', 1);     % Plot answer
                    line(app.axesMain, [edaMinTime(j) edaMaxTime(j)], [edaMinDat(j) edaMinDat(j)], 'Color', 'k', 'LineWidth', 1);     % Plot answer
                    line(app.axesMain, [edaMinTime(j) edaMaxTime(j)], [edaMaxDat(j) edaMaxDat(j)], 'Color', 'k', 'LineWidth', 1);     % Plot answer
                end
            end

            %% Mark special events
            if(~isempty(app.EDAANALYSIS.sPos))
                for j=1:length(app.EDAANALYSIS.sPos)
                    % In case they are valid for the current trial
                    if(app.EDAANALYSIS.sPosTime(i,j) >= 0)
                        line(app.axesMain, [app.EDAANALYSIS.sPosTime(i,j) app.EDAANALYSIS.sPosTime(i,j)], yRangeMarker, 'Color', app.EDAANALYSIS.sPos{j}.color, 'LineWidth', 1);
                        text(app.axesMain, app.EDAANALYSIS.sPosTime(i,j)*1.01, yRangeMarker(2)+evYPos, [app.EDAANALYSIS.sPos{j}.name ' | ' num2str(app.EDAANALYSIS.sPosTime(i,j)) 's'], 'color', app.EDAANALYSIS.sPos{j}.color, 'Interpreter', 'none');
                        evYPos = evYPos + yDist;
                    end
                end
            end

            %% Set axes to have a nice view
            app.axesMain.XLim   = [0 trialTime(end)];
            app.axesMain.XTick  = linspace(0, trialTime(end), 10);
            if(YLim(1) > YLim(2))
                app.axesMain.YLim = fliplr(YLim);
            end
            app.axesMain.YLim   = YLim;
            app.axesMain.YTick  = linspace(YLim(1), YLim(2), 10);

            %% Grid on and hold off
            grid(app.axesMain, 'on')
            hold(app.axesMain, 'off');

            %% Add eda's to list box
            % Create cell of string(s)
            for j=1:length(app.EDAANALYSIS.edaRes(i).edaTimeRes)
                listStr{j} = sprintf('%.3fs | %.3fs | %.3fµS', app.EDAANALYSIS.edaRes(i).edaTimeRes(j), app.EDAANALYSIS.edaRes(i).minTime(j), app.EDAANALYSIS.edaRes(i).amplitude(j));
            end

            % Fill listbox with life ;)
            if(~isempty(app.EDAANALYSIS.edaRes(i).edaTimeRes))
                app.ListBox.Items = listStr;
            else
                app.ListBox.Items = {'No result'};
            end
        end


        function computeEDA(app, data, stimTime)
            % This is the main function that computes the EDA

            % Tobias Otto
            % 1.5
            % 20.04.2021

            % 15.12.2020, Tobias: code adapted from EDA-Analyses 2 to work with the new AppDesigner
            % 17.12.2020, Tobias: added check for findpeaks
            % 09.02.2021, Tobias: computation now again for multiple trials possible
            % 10.02.2021, Tobias: added missing app setting entries in edaRes
            % 08.04.2021, Tobias: Bugfix in case EDA is monotonically in or decreasing
            % 20.04.2022, Tobias: force derivation for computation if 'License + Acknowledgment' is selected

            %% Init variables
            filterNumerator = app.EDAANALYSIS.filterNumerator;
            minYVal         = app.MinheightinStodefinestartEditField.Value;
            minXVal         = app.MinwidthinsecondstodefinestartEditField.Value;
            minPeakWidth    = round( app.MinpeakwidthinsecondsEditField.Value * app.EDAANALYSIS.sampRate );
            peakPromMax     = app.ProminenceMaxEditField.Value;
            peakPromMin     = app.ProminenceMinEditField.Value;
            useDerivation   = contains(app.TabGroup.SelectedTab.Title, 'Derivation');
            useProminence   = app.EnableProminenceCheckBox.Value;
            useFindPeaks    = contains(app.TabGroup.SelectedTab.Title, 'Peaks');
            timeWinStart    = app.StartoftimewindowinsecondsEditField.Value;   % Start time of the time window that was used to "cut" the data

            % If a tab different than derivation or peaks is used, force derivation
            if(useDerivation == 0 && useProminence == 0 && useFindPeaks == 0)
                useDerivation = true;
            end


            %% Filter and compute data
            % Filter data
            data = filter(filterNumerator, 1, data);

            % Use moving average to smooth data even more
            % Use 5% of sampling rate as window size
            windowSize  = ceil(app.EDAANALYSIS.sampRate *0.05);
            b           = (1/windowSize)*ones(1,windowSize);
            data       	= filter(b, 1, data);

            % Remove time shift due to filtering in the beginning
            % Remove additional data in the end
            data(1:round(length(filterNumerator)/2))            = [];
            data(end-(round(length(filterNumerator)/2))+1:end)  = [];

            %% Compute EDA
            if(useFindPeaks == 1)
                % Check if we can use findpeaks
                if(exist('findPeaks', 'File') ~= 2)
                    fid = errordlg(['This program needs the signal processing toolbox and ' ...
                        'can''t work without! Please install the signal processing toolbox ' ...
                        'and try again']);
                    waitfor(fid)
                    return
                end

                % Get maxima and minima using findpeaks
                % Get maximum - first to get the prominent values if it is set to zero
                if(useProminence == 1)
                    % Compute prominence for max values if nothing is set
                    if(peakPromMax == 0)
                        [~,~,~,promPos] = findpeaks(data, 'MinPeakProminence',0, 'MinPeakWidth', minPeakWidth);
                        peakPromMax = max(promPos)/2;
                    end
                    % Finally compute maxima
                    [peakMax,posMax] = findpeaks(data, 'MinPeakProminence',peakPromMax, 'MinPeakWidth', minPeakWidth);
                else
                    [peakMax,posMax] = findpeaks(data, 'MinPeakWidth', minPeakWidth);
                    peakPromMax      = 0;
                end

                % Get minimum
                if(useProminence == 1)
                    % Compute prominence for min values if nothing is set
                    if(peakPromMin == 0)
                        [~,~,~,promPos] = findpeaks(-1*data, 'MinPeakProminence',0, 'MinPeakWidth', minPeakWidth);
                        peakPromMin = max(promPos)/2;
                    end
                    % Finally compute minima
                    [peakMin,posMin] = findpeaks(-1*data, 'MinPeakProminence',peakPromMin, 'MinPeakWidth', minPeakWidth);
                else
                    [peakMin,posMin] = findpeaks(-1*data, 'MinPeakWidth', minPeakWidth);
                    peakPromMin      = 0;
                end

                % Invert peakMin again, because we multiplied data by -1 to get minima!
                peakMin = -1*peakMin;

                % Check if the first minimum is before the first maximum and adjust if
                % necessary
                if(~isempty(peakMax) && ~isempty(peakMin))
                    if(posMax(1) < posMin(1))
                        posMax(1)       = [];
                        peakMax(1)      = [];
                    end

                    % Check length and adjust to equal length
                    if(length(posMin) > length(posMax))
                        del = length(posMin) - length(posMax);
                        posMin(end-del+1:end)   = [];
                        peakMin(end-del+1:end)  = [];
                    elseif(length(posMax) > length(posMin))
                        del = length(posMax)-length(posMin);
                        posMax(1:del)   = [];
                        peakMax(1:del)  = [];
                    end
                end
            elseif(useDerivation == 1)
                % Compute EDA with derivation
                siz = size(data);
                if(siz(1) > siz(2))
                    data = data';
                end
                deriv   = diff(data);
                deriv   = [deriv(1) deriv];

                %% Get sign of derivation
                derivSign  = sign(deriv);
                % Find positions of sign change (2:neg->pos -2:pos->neg)
                signChange = [0 diff(derivSign)];   % correct length due to diff

                % Find ascending and descending EDA data
                startEDA   	= find(signChange==2);
                stopEDA   	= find(signChange==-2);

                % If first sign change is from pos to neg (-2) the trial starts with max
                if(signChange(find(signChange~=0, 1)) == -2)
                    startEDA = [1 startEDA];
                end

                % If last sign change is from neg to pos (2) the trial ends with inhaling!
                if(signChange(find(signChange~=0, 1, 'last')) == 2)
                    % Add a fake start Exhale at the end of trial to get a valid breath in
                    % the analysis
                    stopEDA = [stopEDA length(data)];
                end


                % Check if we have descending data in the beginning
                if(~isempty(stopEDA) && stopEDA(1) < startEDA(1))
                    stopEDA(1) = [];
                end

                % Check number of inhalations and exhalations
                if(~isempty(startEDA) && length(startEDA) > length(stopEDA))
                    startEDA(end) = [];
                end

                %% Modify variables to work with old code
                posMin	= startEDA;
                posMax	= stopEDA;

                % Get data for each time point
                peakMin	= data(startEDA);
                peakMax	= data(stopEDA);
            else
                error('Please choose correct computation for EDA. This is a coding error not yours :(');
            end

            %% Check vectors
            if(~isempty(peakMax) && ~isempty(peakMin))
                % Only use extrema where the y value between max and min is bigger
                % then defined in "minimal Y"
                index   = (peakMax-peakMin) > minYVal;
                peakMax = peakMax(index);
                posMax  = posMax(index);
                peakMin = peakMin(index);
                posMin  = posMin(index);

                % Only use extrema where the x value between local min and local max
                % is bigger than defined in "minimal X"
                index   = ((posMax-posMin)/app.EDAANALYSIS.sampRate) > minXVal;
                peakMax = peakMax(index);
                posMax  = posMax(index);
                peakMin = peakMin(index);
                posMin  = posMin(index);
            end

            %% Remap index to time
            if(~isempty(peakMax) && ~isempty(peakMin))
                minTime     = stimTime + posMin/app.EDAANALYSIS.sampRate + timeWinStart;
                maxTime     = stimTime + posMax/app.EDAANALYSIS.sampRate + timeWinStart;
                minData     = peakMin;
                maxData     = peakMax;
            else
                minTime     = [];
                maxTime     = [];
                maxData     = [];
                minData     = [];
            end

            %% Copy to eda result struct
            i                                       = app.EDAANALYSIS.i;
            app.EDAANALYSIS.edaRes(i).minTime       = minTime;
            app.EDAANALYSIS.edaRes(i).maxTime       = maxTime;
            app.EDAANALYSIS.edaRes(i).minData       = minData;
            app.EDAANALYSIS.edaRes(i).maxData       = maxData;
            app.EDAANALYSIS.edaRes(i).amplitude     = maxData-minData;
            app.EDAANALYSIS.edaRes(i).stimOnset     = stimTime;
            app.EDAANALYSIS.edaRes(i).edaTimeRes    = app.EDAANALYSIS.edaRes(i).minTime-app.EDAANALYSIS.edaRes(i).stimOnset;     % Start time of EDA peak with reference to stimulus onset
            app.EDAANALYSIS.edaRes(i).useProm       = useProminence;
            app.EDAANALYSIS.edaRes(i).promMax       = peakPromMax; % Copy prominence as well
            app.EDAANALYSIS.edaRes(i).promMin       = peakPromMin;
            app.EDAANALYSIS.edaRes(i).minPeakWidth  = minPeakWidth/app.EDAANALYSIS.sampRate;
            app.EDAANALYSIS.edaRes(i).reload        = 0;
            app.EDAANALYSIS.edaRes(i).useDerivation = useDerivation;
            app.EDAANALYSIS.edaRes(i).useFindPeaks  = useFindPeaks;
            app.EDAANALYSIS.edaRes(i).minYVal       = minYVal;      % This entyr was missing in previous version
            app.EDAANALYSIS.edaRes(i).timeWinStart  = timeWinStart; % This entyr was missing in previous version
        end

        function results = computeFilterSettings(app, samplRate)
            % This is the main function for the filter that is used in this
            % APP. Initialization is done during loading of raw or computed
            % data
            %
            % Generated by MATLAB(R) 9.9 and Signal Processing Toolbox 8.5.,  Generated on: 23-Nov-2020 16:24:26
            % Equiripple Lowpass filter designed using the FIRPM function. All frequency values are in Hz.
            % Modified by Tobias Otto, 23.11.2020

            % Tobias Otto
            % 1.0
            % 08.02.2021

            % 08.02.2021, Tobias: first draft

            % Compute filter settings for EDA analysis, based on the loaded data
            Fpass = 4;                % Passband Frequency
            Fstop = 5;                % Stopband Frequency
            Dpass = 0.0028782234183;  % Passband Ripple
            Dstop = 0.01;             % Stopband Attenuation
            dens  = 20;               % Density Factor

            % Calculate the order from the parameters using FIRPMORD.
            [N, Fo, Ao, W] = firpmord([Fpass, Fstop]/(samplRate/2), [1 0], [Dpass, Dstop]);

            % Calculate the coefficients using the FIRPM function.
            b  = firpm(N, Fo, Ao, W, {dens});
            Hd = dfilt.dffir(b);

            % We only export the coefficiants
            results = Hd.Numerator;
        end

        function num = selectData4Import(app, labels)
            % This is a callback function that is used to select data used for EDA
            % analysis

            % Tobias Otto
            % 1.2
            % 22.02.2021

            % 05.12.2017, Tobias: first draft
            % 10.12.2020, Tobias: modified code to work with AppDesigner
            % 22.02.2021, TObias: more user info; corrected function output

            %% Init variables
            global SELECTDATA4IMPORT
            % Copy to global struct
            SELECTDATA4IMPORT.res   = '';

            %% Create GUI items
            fm      = uifigure;
            lb      = uilistbox(fm);
            btn     = uibutton(fm);
            lab1    = uilabel(fm);
            lab2    = uilabel(fm);

            % Get sizw of parent: [left bottom width height]
            pSiz    = get(lb.Parent, 'Position');
            pW      = pSiz(3);
            pH      = pSiz(4);

            % Copy list box and figure handle to global struct for use in sub fcuntion
            SELECTDATA4IMPORT.lb = lb;
            SELECTDATA4IMPORT.fm = fm;

            %% Get data for list box
            for i=1:size(labels, 1)
                tmp         = labels(i,:);
                tmp         = tmp(tmp~=0);
                labDat{i}   = tmp;
            end

            %% Position items and fill with life
            % list box in middle of screen
            lbW     = 300;  % Width of list box in pixel
            lbH     = 200;  % Height of list box in pixel
            labH	= 30;   % Heigth of label box
            lab2W 	= fm.Position(3);   % Width of label box 2

            % Compute list box position
            lb.Position = round([pW/2-lbW/2 pH/2-lbH/2 lbW lbH]);
            lb.Items    = labDat;
            %lb.ValueChangedFcn = @(x,y) (global SELECTDATA4IMPORT, SELECTDATA4IMPORT.res = SELECTDATA4IMPORT.lb.Value);

            lab1.Position               = round([pW/2-lbW/2, 7*pH/8, lbW, labH]);
            lab1.HorizontalAlignment    = 'center';
            lab1.FontSize               = 18;
            lab1.Text                   = 'Select dataset for EDA Analysis';

            lab2.Position               = round([pW/2-lab2W/2, 4*pH/5, lab2W, labH]);
            lab2.HorizontalAlignment    = 'center';
            lab2.FontSize               = 14;
            lab2.Text                   = sprintf('The selected file contains more than one recording or unknown names.\n Please select the recording with valid data!');

            % Button lower rigth corner
            btn.Position        = round([3*pW/4 pH/8 100 22]);
            btn.Text            = 'Select and Exit';
            btn.ButtonPushedFcn = @app.selectData4IMportExitCallback;

            %% Wait to exit and get selected item
            % Wait
            waitfor(fm)

            % Get number
            for i=1:length(labDat)
                if(strcmpi(labDat{i}, SELECTDATA4IMPORT.res))
                    num = i;
                    break;
                end
            end
        end

        function selectData4IMportExitCallback(app, varargin)
            % Use this function to get the selected data set

            % Tobias Otto
            % 1.1
            % 09.02.2021

            % 05.12.2017, Tobias: first draft
            % 09.02.2021, Tobias: modified code to work with AppDesigner
            global SELECTDATA4IMPORT
            SELECTDATA4IMPORT.res = SELECTDATA4IMPORT.lb.Value;
            close(SELECTDATA4IMPORT.fm)
        end

        function results = exportEDA2Excel(app, resFile, resPath, timeStart, timeStop)
            % Use this function to export the computed EDA's to an Excel file. Only the
            % largest EDA is exported per trial.
            % By default this function creates an Excel file with one EDA per trial
            % and optional as many Excel files as optional positions were specified.
            % These files only print EDA values for a trial, if the special position
            % occured in the trial. In addition tht time of the EDA is based on the
            % optional poisition

            % Tobias Otto
            % 1.4
            % 22.06.2021

            % 14.11.2017, Tobias: first draft, based on exportEDA
            % 16.11.2017, Tobias: added more outputs to Excel files
            % 20.11.2019, Tobias: added check for no optional markers
            % 09.02.2021, Tobias: modified code to work with AppDesigner
            % 22.06.2021, Tobias: save in mat file as well; replaced xlswrite

            %% Init variables
            trials      = 1:length(app.EDAANALYSIS.edaRes);
            if(any(app.EDAANALYSIS.sPosTime ~= -99))
                numSPos = size(app.EDAANALYSIS.sPosTime,2);
            else
                numSPos = 0;
            end

            partsOnly   = 0;
            if(~isempty(timeStart) && ~isempty(timeStop))
                partsOnly = 1;
            end

            %% Find max EDA for each trial
            for i=1:length(app.EDAANALYSIS.edaRes)
                % Init variables
                if(partsOnly == 0)
                    timeStart   = app.EDAANALYSIS.trialTime{i}(1);
                    timeStop    = app.EDAANALYSIS.trialTime{i}(end);
                end

                % Find EDA for the fiven time range
                index       = app.EDAANALYSIS.edaRes(i).edaTimeRes >= timeStart & app.EDAANALYSIS.edaRes(i).edaTimeRes <= timeStop;
                tmpAmpl     = app.EDAANALYSIS.edaRes(i).amplitude(index);
                tmpTime     = app.EDAANALYSIS.edaRes(i).edaTimeRes(index);

                if(~isempty(tmpAmpl))
                    % Find the max EDA in the given time range
                    [edaMax(i), pos]    = max(tmpAmpl);
                    edaTime(i)          = tmpTime(pos);    % Time with reference to stimulus onset
                else
                    edaMax(i)   = 0;
                    edaTime(i)  = 0;
                end
            end

            %% Separate eda by time and trial
            % Init veriables to zero
            resSPos     = zeros(numSPos, length(app.EDAANALYSIS.edaRes));
            timeSPos    = zeros(numSPos, length(app.EDAANALYSIS.edaRes));

            % Copy eda and time values for each trial
            % Time is with reference to stimulus onset!
            res	= edaMax;
            tim	= edaTime;

            % And now only trials with special Positions
            for i=1:numSPos
                ind             = app.EDAANALYSIS.sPosTime(:,i)' >= 0;
                timeSPos(i,ind)	= edaTime(ind) - app.EDAANALYSIS.sPosTimeStimOnset(ind,i)';
                resSPos(i,ind)	= edaMax(ind);

                % If time is negative, remove entry
                ind2                = timeSPos(i,:) < 0;
                timeSPos(i,ind2)	= 0;
                resSPos(i,ind2)     = 0;

                % Save position of sPlus a sindex
                sPInd(i,:)	= app.EDAANALYSIS.sPos{i}.time > 0;
            end

            %% Save result of Stimulus onset EDA
            % Write header
            mat     = [{'Trial'} {'maxEda'} {'time(StimOnset)'}];

            % Write to file for general EDA
            j = 2;
            for i=1:length(app.EDAANALYSIS.edaRes)
                mat(j,:)	= [{trials(i)}, {res(i)}, {tim(i)}];
                j           = j+1;
            end

            % Make it look nice for SPSS
            mat     = mat';
            allEDA  = mat;                  % Save variable for mat file
            allEDA  = cell2table(allEDA);

            % Create file name and save
            filename = [resPath resFile];
            disp(['Saving EDA''s for all trials to: ' filename]);
            writecell(mat, filename);
            disp('done');

            %% Save result to EDA file with special/optional positions
            for k=1:numSPos
                clear mat mat2

                % Write header
                mat     = [{'Trial'} {'maxEda'} {['time(' app.EDAANALYSIS.sPos{k}.name ')']} {[app.EDAANALYSIS.sPos{k}.name ' active']}];
                mat2    = mat;

                % Write to file for general EDA
                j = 2;
                l = 2;
                for i=1:length(app.EDAANALYSIS.edaRes)
                    % Store all trials
                    mat(j,:)	= [{trials(i)}, {resSPos(k,i)}, {timeSPos(k,i)}, {double(sPInd(k,i))}];
                    j           = j+1;

                    % Store only trials with an active marker
                    if(sPInd(k,i) == 1)
                        mat2(l,:)	= [{trials(i)}, {resSPos(k,i)}, {timeSPos(k,i)}, {double(sPInd(k,i))}];
                        l           = l+1;
                    end
                end

                % Make it look nice for SPSS
                mat     = mat';
                mat2    = mat2';

                % Create file name and save
                filename = [resPath resFile];
                disp(['Saving EDA for ' app.EDAANALYSIS.sPos{k}.name ' to ' filename]);
                writecell(mat, filename, 'Sheet', app.EDAANALYSIS.sPos{k}.name);
                writecell(mat2, filename, 'Sheet', [app.EDAANALYSIS.sPos{k}.name '_active']);
                disp('done');

                % Create a mat file as well
                sPosAllEDA          = mat;
                sPosActiveMarker    = mat2;
                % Convert to table
                sPosAllEDA          = cell2table(sPosAllEDA);
                sPosActiveMarker    = cell2table(sPosActiveMarker);
                % Finally save mat file
                save([resPath resFile '.mat'], 'allEDA', 'sPosAllEDA', 'sPosActiveMarker', '-mat');
            end
        end

        function essbids_writeTsv(app,fn_tsv,myTable,noHeader)
            %% ESSBIDS_WRITETSV
            %
            % This function transfers tables to tab seperated variable files.
            % Depending on wether a valid, non-empty timevector input is provided the
            % output file will be headerless (e.g., "*_physio.tsv") or include headers
            % (e.g., "participants.tsv"). Json input finaly is purely optional.
            %
            % Empty cells and Not-a-number or Not-a-Time values (NaN, NaT) will be
            % replaced by "n/a".
            %
            %
            %  2021-12-14 : added optional input noHeader (Tobias Otto)
            %  2021-12-02 : restructured and commented, include json support
            %  2021-10-21 : allow for gz extension
            %
            %  @author    : Thomas M. Ernst
            %  @email     : thomas.m.ernst@uni-due.de
            %  @institute : University Hospital Essen, Essen, Germany

            tsv_param = {'filetype','text','delim','tab'};


            if nargin < 4
                noHeader = 0;
                %     myTimeVector= [];
            end
            % decide wether header is used bases on timevector input
            % wHdr = ~isempty(myTimeVector) && numel(myTimeVector)==size(myTable,1);


            fn_out = fn_tsv;
            [fp,fn,fe] = fileparts(fn_tsv);
            if strcmpi(fe,'.gz')
                fn_out = fullfile(fp,fn);
            end

            % replace empty cells with string "n/a"
            vn = myTable.Properties.VariableNames;
            for i=1:numel(vn)
                if iscell(myTable.(vn{i}))
                    ind = cellfun(@isempty,myTable.(vn{i}));
                    myTable.(vn{i})(ind) = {'n/a'};
                end
            end

            % write the table to the tsv file
            % if wHdr
            % writetable(myTable,fn_out,tsv_param{:});
            % else
            %   writetable(myTable,fn_out,tsv_param{:},'WriteVariableNames',false);
            % end

            if noHeader == 0
                writetable(myTable,fn_out,tsv_param{:});
            else
                writetable(myTable,fn_out,tsv_param{:},'WriteVariableNames',false);
            end

            % modify tsv file to remove invalid outputs
            txt0 = fileread(fn_out);
            txt = strrep(txt0,'NaN','n/a');
            txt = strrep(txt,'NaT','n/a');
            if not(isequal(txt0,txt))
                fid = fopen(fn_out,'w');
                fprintf(fid,txt);
                fclose(fid);
            end

            % zip the output file if required
            if strcmpi(fe,'.gz')
                gzip(fn_out);
                delete(fn_out);
            end
        end

        function essbids_writeJson(app,jsonFilename,struct2convert)
            % ESSBIDS_WRITEJSON
            %
            % converts a structure to json text and saves it. Since R2021a MATLAB can
            % format pretty json strings, for older versions of MATLAB the script tries
            % to call a python function to "prettify" the json output, i.e. reformat
            % it, to make it more easily human readible.
            %   >> python3 -m json.tool
            %
            % If the input is just the name of an existing json file, and no
            % struct2convert is provided, the script will try to just prettify it.
            %
            % This removes generic MATLAB placeholders (e.g., MATLAB does not allow a
            % field to be named "1", it will always be named "x1" instead. This is
            % corrected in this script before writing the json file.
            % ATTENTION: hence it is not possible to actually name any field
            %            x<intergernumber>
            %
            %  2021-12-13 : Integration of prettyByMatlab for R2021A or younger,
            %               subfunctions replacePlaceholders and prettifyWithPython
            %               introduced to simplyfy main sytax
            %
            %  @author    : Thomas M. Ernst
            %  @email     : thomas.m.ernst@uni-due.de
            %  @institute : University Hospital Essen, Essen, Germany
            %
            % Modified to work with EDA-App, Tobias Otto, 19.12.2021

            prettyByML= hex2dec(version('-release'))>=hex2dec('2021a');

            if exist(jsonFilename,'file')==2 && nargin < 2
                % reformat existing json file

                txt0 = fileread(jsonFilename);
                if prettyByML
                    prettyStr = jsonencode(jsondecode(txt0),'ConvertInfAndNaN',true,'PrettyPrint',true);
                    prettyStr = app.essbids_replacePlaceholders(prettyStr);
                end

                % save only when reformated file differs from original
                if not(isequal(prettyStr,txt0))
                    fid = fopen(jsonFilename,'w');
                    fprintf(fid,'%s',prettyStr);
                    fclose(fid);
                end

            else
                % create a new / overwrite an old json file

                % transform structure to json string
                if prettyByML
                    strTemp = jsonencode(struct2convert,'ConvertInfAndNaN',true,...
                        'PrettyPrint',true);
                else
                    strTemp = jsonencode(struct2convert,'ConvertInfAndNaN',true);
                end

                % replace generic placeholder x of numeric fieldnames, e.g. rename field "x1" to "1" in json text
                strTemp = app.essbids_replacePlaceholders(strTemp, 2); % Keep + and -, but remove all other special characters

                % save to file
                fid = fopen(jsonFilename,'w');
                fprintf(fid,'%s',strTemp);
                fclose(fid);
            end

        end

        function str_cleaned = essbids_replacePlaceholders(app, str2clean, cleanMode)
            % replace generic placeholder x of numeric fieldnames ,
            % e.g. rename field "x1" to "1" in json text
            %
            %  @author    : Thomas M. Ernst
            %  @email     : thomas.m.ernst@uni-due.de
            %  @institute : University Hospital Essen, Essen, Germany

            % Tobias: added +,- strings to replace only in some cases
            % Tobias: using this function also for filenames (remove - and _)
            % Tobias: adding more options to rmove + and - from string

            strTemp = str2clean;
            str2rep = { % Umlaut etc. conversion
                'ä' 'ae'
                'Ä' 'Ae'
                'ö' 'oe'
                'Ö' 'Oe'
                'ü' 'ue'
                'Ü' 'Ue'
                'ß' 'ss'
                '?' ''
                'â' 'a'
                'È' 'E'
                'è'	'e'
                'É' 'E'
                'é'	'e'
                'ê'	'e'
                'ë'	'e'
                'ñ' 'n'
                'Ø'	'O'
                'ø'	'o'
                '_' ''
                '+' ''
                '-' ''};  % Don't change order! Last one has to be + and -
            
            % Add replacement or delete certain character
            if(nargin == 3)
                if(cleanMode == 1)
                    % Keep + and -, but rename it to plus and minus
                    str2rep(end-1,:)  = {'+' 'plus'}; 
                    str2rep(end,:)    = {'-' 'minus'};
                elseif(cleanMode == 2)
                    % % Keep + and -, but remove all other special characters
                    str2rep(end-1:end,:) = [];
                elseif(cleanMode == 3)
                    % Same as 1, but remove whitespace
                    str2rep(end-1,:)  = {'+' 'plus'}; 
                    str2rep(end,:)    = {'-' 'minus'};
                    str2rep(end+1,:) = {' ' ''};
                end
            end

            for i=1:size(str2rep,1)
                strTemp = strrep(strTemp,str2rep{i,1},str2rep{i,2});
            end

            fn2repl = unique(struct2cell(regexp(strTemp,'(?<fn>"x_[0-9]+")','names')));

            for i=1:numel(fn2repl)
                strTemp = strrep(strTemp,fn2repl{i},...
                    [fn2repl{i}(1),'-',fn2repl{i}(4:end)]);
            end
            fn2repl = unique(struct2cell(...
                regexp(strTemp,'(?<fn>"x[0-9]+")','names')));
            for i=1:numel(fn2repl)
                strTemp = strrep(strTemp,fn2repl{i},fn2repl{i}([1,3:end]));
            end
            str_cleaned = strTemp;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This method is called during startup

            % Tobias Otto
            % 1.1
            % 24.11.2021

            % 08.07.2021, Tobias: first draft
            % 24.11.2021, Tobias: updated version check

            % Check MATLAB version
            % ==> This check is too late here for the 'wraptext problem' :(
            % Leave it anyway, maybe we find a better solution on some point
            a = ver('MATLAB');
            v = str2double(regexp(a.Version, '\.', 'split'));
            if(v(1) < 9 || v(2) < 8)
                fid = errordlg(['This program requires at least' ...
                    'MATLAB 2020a!' ...
                    'Please install a newer version of MATLAB and try again']);
                waitfor(fid)
            end

            % Add check for the signal processing toolbox
            if(exist('findPeaks', 'file') ~= 2 && exist('firpmord', 'file') ~= 2)
                fid = errordlg(['This program needs the signal processing toolbox and ' ...
                    'can''t work without! Please install the signal processing toolbox ' ...
                    'and try again']);
                waitfor(fid)
            end

            % Init variables (also done in loadRawData)
            app.initEDAData;
        end

        % Button pushed function: buttonLoadRawData
        function loadRawData(app, event)
            % This method loads raw data into the app

            % Tobias Otto
            % 1.4
            % 19.12.2021

            % 23.11.2020, Tobias: code adapted from EDA-Analyses 2 to work with the new AppDesigner
            % 08.02.2021, Tobias: moved to callbacks
            % 01.03.2021, Tobias: renamed result file to "trial definition" file
            % 06.04.2021, Tobias: bugfix for Brainvision files
            % 19.12.2021, Tobias: added bids as variable to load and 'Biopac' as system

            %% Init variables
            bids = [];

            % If this function is called all available user data can be deleted.
            app.initEDAData;

            % Reset application settings as well
            app.axesMain.XLim   = [0 1];
            app.axesMain.XTick  = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axesMain.YLim   = [0 1];
            app.axesMain.YTick  = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];

            %% Get filenames and load data
            % Get EDA filename
            [edaFile, pEDA] = uigetfile('*.mat', 'Select EDA file');

            % Get trial configuration file
            [configFile, pConf] = uigetfile({'*.m;*.mat'}, 'Select trial definition file (*.m or *.mat)', pEDA);

            if(pConf == 0)
                return
            else
                configFilePath = pConf;
            end

            % Get extention
            [~, ~, ext] = fileparts(configFile);

            % Get data in either way
            if(strcmpi(ext, '.m'))
                run([pConf, configFile]);
            else
                load([pConf, configFile], 'numTrials', 'trialSep', 'trialStart', 'trialStop', 'stimOnset', 'sPos', ...
                    'samplRate', 'recordingSystem', 'bids');
            end

            % Now load EDA data depending on the recording system
            if(pEDA == 0)
                return
            else
                edaFilePath = pEDA;
                if(strcmpi(recordingSystem, 'GSR100C') || strcmpi(recordingSystem, 'Biopac'))
                    load([pEDA,edaFile], 'data', 'labels', 'units', 'isi_units')
                elseif(strcmpi(recordingSystem, 'Visionrecorder'))
                    load([pEDA,edaFile], 'data', 'xUnits', 'yUnits')
                end
            end


            %% Check user input
            if(~isempty(trialSep))
                if(numTrials+1 ~= length(trialSep))
                    fid = warndlg(['The number of trials does NOT fit to the number of trial separators. ' ...
                        ' ' ...
                        'numTrials+1 bust be equal to the number of trial separators.']);
                    waitfor(fid);
                    go = 0;
                end

                % Check consistency of time entries
                if(~all(diff(trialSep) > 0))
                    fid = warndlg(['Check trial separation (time entries) for consistency! ' ...
                        ' ' ...
                        'One or more entries are wrong. I give up! Please correct and try again.']);
                    waitfor(fid);
                    go = 0;
                end
            end

            if(~isempty(trialStart) || ~isempty(trialStop))
                if(length(trialStart) ~= length(trialStop))
                    fid = warndlg(['The number of entries in trialStart and trialStop' ...
                        'are not equal. I give up!']);
                    waitfor(fid);
                    go = 0;
                end

                if(length(trialStart) ~= numTrials)
                    fid = warndlg(['The number of entries in trialStart and trialStop' ...
                        'are not equal to the values entered in numTrials. I give up!']);
                    waitfor(fid);
                    go = 0;
                end
            end


            if((~isempty(trialStart) || ~isempty(trialStop)) && ~isempty(trialSep))
                fid = warndlg(['Please double check your trial definition file!' ...
                    ' ' ...
                    'The variables  trialStart, trialStop and trialSep exist. This is not valid', ...
                    'Please only use trialSep and leave the others empty or use trialStart and trialStop', ...
                    'and leave trialSep empty. I give up!']);
                waitfor(fid);
                go = 0;
            end

            % Does optional variable sPos exist?
            if(exist('sPos','var') == 0)
                sPos = [];
            end

            %% Compute "real" value of EDA Data for BIOPAC systems
            % We recorded the EDA with HP filter of 0.05Hz
            % Therefore we have to adjust the measured values according to the
            % guidelines described in the GSR100C handbook:
            %
            % In the scaling window, set the input voltages so they map to the “0.05 Hz” conductance ranges indicated by
            % the sensitivity setting. For example if the GSR100C is set to a Gain of 5 ?mho/V, then 0 V will map to X
            % ?mhos and 1V will map to (X+5) ?mhos. Where “X” is the mean conductance being recorded.

            if(strcmpi(recordingSystem, 'GSR100C') || strcmpi(recordingSystem, 'Biopac'))
                % First try to get the EDA data out of the MATLAB file created by
                % AcqKnowledge. First try with string 'GSR100C', because that's what we
                % use.
                good = 0;
                for i=1:size(labels,1)
                    if(contains(labels(i,:), 'GSR100C'))
                        good = 1;
                        break
                    end
                end
                % If that still doesn't work take the first vector from the data
                % variable!
                if(good == 0)
                    i = app.selectData4Import(labels);
                end

                % Copy only the EDA data into variables
                data = data(:,i);

                data        = data*5+mean(data);
                xUnits      = isi_units;
                yUnits      = units(i,:);
                yUnits      = yUnits(yUnits ~= 0);
                edaAdjusted = 'YES -> data*5+mean(data)';
                manufacturer= 'Biopac';
                go          = 1;
            elseif(strcmpi(recordingSystem, 'Visionrecorder'))
                edaAdjusted = 'NO -> only imported';
                manufacturer= 'Brainvision';
                go          = 1;
            else
                fid = warndlg(['The defined recording system "' recordingSystem ...
                    '" does not exist in my configuration. I give up!']);
                waitfor(fid);
                go = 0;
            end

            %% Check, if loaded data are valid
            % Not a strong check, but better than nothing
            if(~exist('data', 'var') || ~isnumeric(data))
                fid = warndlg('The MATLAB file does NOT contain valid EDA data. I give up!');
                waitfor(fid);
                go = 0;
            end

            if(~exist('xUnits', 'var') || ~ischar(xUnits))
                fid = warndlg('The MATLAB file does NOT contain valid xUnits data. I give up!');
                waitfor(fid);
                go = 0;
            end

            if(~exist('yUnits', 'var') || ~ischar(yUnits))
                fid = warndlg('The MATLAB file does NOT contain valid yUnits data. I give up!');
                waitfor(fid);
                go = 0;
            end


            %% Compute filter settings for EDA analysis, based on the loaded data
            filterNumerator = app.computeFilterSettings(samplRate);

            %% Add user info
            app.labelFileName.Text = [pEDA,edaFile];

            %% Copy data to EDAANALYSIS property
            app.EDAANALYSIS.numTrials           = numTrials;
            app.EDAANALYSIS.trialSep            = trialSep;
            app.EDAANALYSIS.trialStart          = trialStart;
            app.EDAANALYSIS.trialStop           = trialStop;
            app.EDAANALYSIS.stimOnset           = stimOnset;
            app.EDAANALYSIS.sPos                = sPos;
            app.EDAANALYSIS.sampRate            = samplRate;
            app.EDAANALYSIS.edaFileName         = edaFile;
            app.EDAANALYSIS.edaFilePath         = edaFilePath;
            app.EDAANALYSIS.configFileName      = configFile;
            app.EDAANALYSIS.eda                 = data;
            app.EDAANALYSIS.edaAdjusted         = edaAdjusted;
            app.EDAANALYSIS.i                   = 1;
            app.EDAANALYSIS.xlabel              = xUnits;
            app.EDAANALYSIS.ylabel              = yUnits;
            app.EDAANALYSIS.edaMinTime          = [];
            app.EDAANALYSIS.edaMaxTime          = [];
            app.EDAANALYSIS.edaMinData          = [];
            app.EDAANALYSIS.edaMaxData          = [];
            app.EDAANALYSIS.edaTimeRes          = [];
            app.EDAANALYSIS.yRangeMarkerFactor  = [0.95 1.05];
            app.EDAANALYSIS.yLimFactor          = [0.8 1.2];
            app.EDAANALYSIS.filterNumerator     = filterNumerator;
            app.EDAANALYSIS.recordingSystem     = manufacturer;
            % Spacial handling for optional bids
            if(~isempty(bids))
                app.EDAANALYSIS.bids = bids;
            end

            %% Call "plot function" to compute first EDA
            if(go == 1)
                % Compute data
                app.computeData2Plot;
                % Plot data
                app.plotEDAData;
            end
        end

        % Value changed function: 
        % EnablestartandendtimeforalltrialsCheckBox
        function enableGlobalStartEndTime(app, event)
            value = app.EnablestartandendtimeforalltrialsCheckBox.Value;
            % Copy to peak tab
            app.EnablestartandendtimeforalltrialsCheckBox_2.Value = value;

            % Set time fields active or inactive due to setting of check box
            if(value == true)
                app.StartoftimewindowinsecondsEditField.Enable  = false;
                app.EndoftimewindowinsecondsEditField.Enable    = false;
            else
                app.StartoftimewindowinsecondsEditField.Enable  = true;
                app.EndoftimewindowinsecondsEditField.Enable    = true;
            end
        end

        % Value changed function: 
        % MinwidthinsecondstodefinestartEditField
        function minWidthInSecondsCopy(app, event)
            value = app.MinwidthinsecondstodefinestartEditField.Value;
            % Copy data to peaks tab
            app.MinwidthinsecondstodefinestartEditField_2.Value = value;
        end

        % Value changed function: MinheightinStodefinestartEditField
        function minHeighInVoltCopy(app, event)
            value = app.MinheightinStodefinestartEditField.Value;
            % Copy data to peaks tab
            app.MinheightinStodefinestartEditField_2.Value = value;
        end

        % Value changed function: MinpeakwidthinsecondsEditField
        function minPeakWidthCopy(app, event)
            value = app.MinpeakwidthinsecondsEditField.Value;
            % Copy data to peaks tab
            app.MinpeakwidthinsecondsEditField_2.Value = value;
        end

        % Value changed function: 
        % StartoftimewindowinsecondsEditField
        function startOfTimeWindowCopy(app, event)
            value = app.StartoftimewindowinsecondsEditField.Value;
            % Copy data to peaks tab
            app.StartoftimewindowinsecondsEditField_2.Value = value;
        end

        % Value changed function: EndoftimewindowinsecondsEditField
        function endOfTimeWindowCopy(app, event)
            value = app.EndoftimewindowinsecondsEditField.Value;
            % Copy data to peaks tab
            app.EndoftimewindowinsecondsEditField_2.Value = value;
        end

        % Value changed function: EnableProminenceCheckBox
        function enableProminence(app, event)
            % Callback function for enabling EDA computation using
            % prominence

            % Tobias Otto
            % 1.0
            % 17.12.2020

            % 17.12.2020, Tobias: first draft

            value = app.EnableProminenceCheckBox.Value;

            if(value == true)
                % Edit TEXT fields
                app.ProminenceMaxLabel.Enable       = true;
                app.ProminenceMinLabel.Enable       = true;
                % Edit fields
                app.ProminenceMaxEditField.Enable   = true;
                app.ProminenceMaxEditField.Editable = true;
                app.ProminenceMinEditField.Enable   = true;
                app.ProminenceMinEditField.Editable = true;
            else
                % Edit TEXT fields
                app.ProminenceMaxLabel.Enable       = false;
                app.ProminenceMinLabel.Enable       = false;
                % Edit fields
                app.ProminenceMaxEditField.Enable   = false;
                app.ProminenceMaxEditField.Editable = false;
                app.ProminenceMinEditField.Enable   = false;
                app.ProminenceMinEditField.Editable = false;
            end
        end

        % Button pushed function: RecomputeEDA_Button
        function recomputeEDA(app, event)
            % Callback function for recomputing and also plotting the EDA

            % Tobias Otto
            % 1.0
            % 16.12.2020

            % 16.12.2020, Tobias: first draft

            % Compute data
            app.computeData2Plot;

            % Plot data
            app.plotEDAData;
        end

        % Button pushed function: yAxisPlus_Button
        function yAxisPlus(app, event)
            % Callback function for zooming in

            % Tobias Otto
            % 1.0
            % 17.12.2020

            % 17.12.2020, Tobias: first draft

            % Change factors
            app.EDAANALYSIS.yLimFactor(1) = app.EDAANALYSIS.yLimFactor(1) + 0.01;
            app.EDAANALYSIS.yLimFactor(2) = app.EDAANALYSIS.yLimFactor(2) - 0.01;
            % Plot again
            app.plotEDAData;
        end

        % Button pushed function: yAxisMinus_Button
        function yAxisMinus(app, event)
            % Callback function for zooming out

            % Tobias Otto
            % 1.0
            % 17.12.2020

            % 17.12.2020, Tobias: first draft

            % Change factors
            app.EDAANALYSIS.yLimFactor(1) = app.EDAANALYSIS.yLimFactor(1) - 0.01;
            app.EDAANALYSIS.yLimFactor(2) = app.EDAANALYSIS.yLimFactor(2) + 0.01;
            % Plot again
            app.plotEDAData;
        end

        % Button pushed function: DeleteselectedButton
        function deleteSelectedEDA(app, event)
            % Callback function to delete a selected EDA

            % Tobias Otto
            % 1.0
            % 17.12.2020

            % 17.12.2020, Tobias: first draft based on EDA-Analysis2

            %% Init variables
            i = app.EDAANALYSIS.i;

            %% Get position of eda that has to be deleted
            pos = find(matches(app.ListBox.Items, app.ListBox.Value));

            % Set to previous position
            if(pos-1 > 0)
                app.ListBox.Items(pos)  = [];
                app.ListBox.Value       = app.ListBox.Items(pos-1);
            else
                app.ListBox.Items{1}    = '';
                app.ListBox.Value       = '';
            end

            %% Delete eda from struct
            app.EDAANALYSIS.edaRes(i).minTime(pos)      = [];
            app.EDAANALYSIS.edaRes(i).maxTime(pos)      = [];
            app.EDAANALYSIS.edaRes(i).minData(pos)      = [];
            app.EDAANALYSIS.edaRes(i).maxData(pos)      = [];
            app.EDAANALYSIS.edaRes(i).amplitude(pos)    = [];
            app.EDAANALYSIS.edaRes(i).edaTimeRes(pos)   = [];

            %% Plot data again
            app.plotEDAData;
        end

        % Button pushed function: MergewithpreviousButton
        function mergeWithPreviousEDA(app, event)
            % Callback function to merge a selected EDA with the previous EDA

            % Tobias Otto
            % 1.1
            % 15.06.2021

            % 17.12.2020, Tobias: first draft based on EDA-Analysis2
            % 15.06.2021, Tobias: Bugfix in computing new amplitude

            %% Init variables
            i = app.EDAANALYSIS.i;

            %% Get position of eda that has to be deleted
            pos = find(matches(app.ListBox.Items, app.ListBox.Value));

            %% Start deleting
            if(pos == 1)
                h = warndlg('There is no previous EDA to merge with. Please select another EDA result!');
                waitfor(h)
            else
                % Copy bew data to previous EDA
                app.EDAANALYSIS.edaRes(i).minTime(pos-1)    = app.EDAANALYSIS.edaRes(i).minTime(pos-1);
                app.EDAANALYSIS.edaRes(i).maxTime(pos-1)    = app.EDAANALYSIS.edaRes(i).maxTime(pos);
                app.EDAANALYSIS.edaRes(i).minData(pos-1)    = app.EDAANALYSIS.edaRes(i).minData(pos-1);
                app.EDAANALYSIS.edaRes(i).maxData(pos-1)    = app.EDAANALYSIS.edaRes(i).maxData(pos);
                app.EDAANALYSIS.edaRes(i).edaTimeRes(pos-1) = app.EDAANALYSIS.edaRes(i).edaTimeRes(pos-1);
                app.EDAANALYSIS.edaRes(i).amplitude(pos-1)  = app.EDAANALYSIS.edaRes(i).maxData(pos-1) - app.EDAANALYSIS.edaRes(i).minData(pos-1);

                % Delete delected entry
                app.EDAANALYSIS.edaRes(i).minTime(pos)      = [];
                app.EDAANALYSIS.edaRes(i).maxTime(pos)      = [];
                app.EDAANALYSIS.edaRes(i).minData(pos)      = [];
                app.EDAANALYSIS.edaRes(i).maxData(pos)      = [];
                app.EDAANALYSIS.edaRes(i).edaTimeRes(pos)   = [];
                app.EDAANALYSIS.edaRes(i).amplitude(pos)    = app.EDAANALYSIS.edaRes(i).maxData(pos-1) - app.EDAANALYSIS.edaRes(i).minData(pos-1);

                % Old Code
                %app.EDAANALYSIS.edaRes(i).minTime(pos)      = [];
                %app.EDAANALYSIS.edaRes(i).maxTime(pos-1)    = [];
                %app.EDAANALYSIS.edaRes(i).minData(pos)      = [];
                %app.EDAANALYSIS.edaRes(i).maxData(pos-1)    = [];
                %app.EDAANALYSIS.edaRes(i).edaTimeRes(pos)	= [];
                %app.EDAANALYSIS.edaRes(i).amplitude(pos-1)  = app.EDAANALYSIS.edaRes(i).maxData(pos-1) - app.EDAANALYSIS.edaRes(i).minData(pos-1);

                % Set focus back to entry before
                app.ListBox.Value = app.ListBox.Items(pos-1);

                %% Plot data again
                app.plotEDAData;
            end
        end

        % Button pushed function: SplitselectedButton
        function splitSelectedEDA(app, event)
            % Callback function to splits a selected EDA into two separate EDA's

            % Tobias Otto
            % 1.2
            % 09.07.2021

            % 17.12.2020, Tobias: first draft based on EDA-Analysis2
            % 22.06.2021, Tobias: modified window size
            % 09.07.2021, Tobias: added selection (old/new) for splitting method

            %% Init variables
            i           = app.EDAANALYSIS.i;
            sampRate    = app.EDAANALYSIS.sampRate;
            minXDist    = app.EDAANALYSIS.edaMaskWindow(i);

            % Get position of selected eda
            pos = find(matches(app.ListBox.Items, app.ListBox.Value));

            % Check if an EDA was selected
            if(isempty(pos))
                h = errordlg('No EDA selected!');
                waitfor(h);
            end

            %% Get data of selected EDA
            % Compute some extra time for start and beginning
            extra       = (app.EDAANALYSIS.edaRes(i).maxTime(pos) - app.EDAANALYSIS.edaRes(i).minTime(pos))*0.05;
            startTime   = app.EDAANALYSIS.edaRes(i).minTime(pos) - extra;
            stopTime    = app.EDAANALYSIS.edaRes(i).maxTime(pos) + extra;
            if(startTime < app.EDAANALYSIS.timeWindowStart(i))
                startTime   = app.EDAANALYSIS.edaRes(i).minTime(pos);
            end
            if(stopTime > app.EDAANALYSIS.timeWindowStop(i))
                stopTime   = app.EDAANALYSIS.edaRes(i).maxTime(pos);
            end

            startInd    = round(startTime*sampRate);
            stopInd     = round(stopTime*sampRate);
            data        = app.EDAANALYSIS.trialData{i}(startInd:stopInd);
            time        = app.EDAANALYSIS.trialTime{i}(startInd:stopInd);

            % Plot in new figure
            fid = figure;
            ax  = axes(fid);
            set(fid, 'Menubar', 'none', 'Name', 'Split selected EDA','Numbertitle', 'off','PaperUnits', 'normalized');
            plot(ax, time, data);
            text(ax, time(round(length(time)/300)) , max(data)*1.01 , 'Please select position where to split the EDA','Fontsize', 14);

            % Make it look a bit nicer
            %axis equal
            grid on
            hold on

            %% Get data
            try
                x = ginput(1);
                x = x(1);   % We need the x component only
            catch
                warning('EDA wasn''t split, because the window was closed !');
            end

            %% Check, if Windows still exists
            if(ishandle(fid))
                %% Compute two new EDAs
                % Compute new start and stop TIME points for the new EDA'a
                % Separate EDA's by the given time window (set up in GUI)
                newStart1   = app.EDAANALYSIS.edaRes(i).minTime(pos);
                % Check which version to use
                if(strcmpi(app.EDAANALYSIS.splitSelectedEDAVersion, 'old'))
                    newStop1    = x - minXDist/4;
                elseif(strcmpi(app.EDAANALYSIS.splitSelectedEDAVersion, 'new'))
                    newStop1    = x;
                end
                newTimeRes1 = newStart1 - app.EDAANALYSIS.edaRes(i).stimOnset;
                % Check which version to use
                if(strcmpi(app.EDAANALYSIS.splitSelectedEDAVersion, 'old'))
                    newStart2   = x + minXDist/4 + 1/sampRate;
                elseif(strcmpi(app.EDAANALYSIS.splitSelectedEDAVersion, 'new'))
                    newStart2   = x + 1/sampRate;
                end
                newStop2    = app.EDAANALYSIS.edaRes(i).maxTime(pos);
                newTimeRes2 = newStart2 - app.EDAANALYSIS.edaRes(i).stimOnset;

                % Compute new max and min DATA points for the two new EDA's
                data1   = app.EDAANALYSIS.trialData{i}(round(newStart1*sampRate:newStop1*sampRate));
                newMin1 = min(data1);
                newMax1 = max(data1);
                ampl1   = newMax1 - newMin1;

                data2   = app.EDAANALYSIS.trialData{i}(round(newStart2*sampRate:newStop2*sampRate));
                newMin2 = min(data2);
                newMax2 = max(data2);
                ampl2   = newMax2 - newMin2;

                %% Copy result to global struct
                % First copy entries after pos to actual position.
                % But only if position is not the last one
                if(length(app.EDAANALYSIS.edaRes(i).minTime) > pos)
                    app.EDAANALYSIS.edaRes(i).minTime(pos+2:end+1)      = app.EDAANALYSIS.edaRes(i).minTime(pos+1:end);
                    app.EDAANALYSIS.edaRes(i).maxTime(pos+2:end+1)      = app.EDAANALYSIS.edaRes(i).maxTime(pos+1:end);
                    app.EDAANALYSIS.edaRes(i).minData(pos+2:end+1)      = app.EDAANALYSIS.edaRes(i).minData(pos+1:end);
                    app.EDAANALYSIS.edaRes(i).maxData(pos+2:end+1)      = app.EDAANALYSIS.edaRes(i).maxData(pos+1:end);
                    app.EDAANALYSIS.edaRes(i).amplitude(pos+2:end+1)    = app.EDAANALYSIS.edaRes(i).amplitude(pos+1:end);
                    app.EDAANALYSIS.edaRes(i).edaTimeRes(pos+2:end+1)	= app.EDAANALYSIS.edaRes(i).edaTimeRes(pos+1:end);
                end

                % Copy new entries
                app.EDAANALYSIS.edaRes(i).minTime(pos)      = newStart1;
                app.EDAANALYSIS.edaRes(i).maxTime(pos)      = newStop1;
                app.EDAANALYSIS.edaRes(i).minData(pos)      = newMin1;
                app.EDAANALYSIS.edaRes(i).maxData(pos)      = newMax1;
                app.EDAANALYSIS.edaRes(i).amplitude(pos)    = ampl1;
                app.EDAANALYSIS.edaRes(i).edaTimeRes(pos)   = newTimeRes1;

                app.EDAANALYSIS.edaRes(i).minTime(pos+1)   	= newStart2;
                app.EDAANALYSIS.edaRes(i).maxTime(pos+1)  	= newStop2;
                app.EDAANALYSIS.edaRes(i).minData(pos+1)  	= newMin2;
                app.EDAANALYSIS.edaRes(i).maxData(pos+1) 	= newMax2;
                app.EDAANALYSIS.edaRes(i).amplitude(pos+1)	= ampl2;
                app.EDAANALYSIS.edaRes(i).edaTimeRes(pos+1) = newTimeRes2;

                %% Plot again and close window
                close(fid)

                %% Plot EDA data
                app.plotEDAData;
            end
        end

        % Button pushed function: SaveEDAButton
        function saveEDA(app, event)
            % Saves the EDAANALYSIS struct as MATLAB file

            % Tobias Otto
            % 1.1
            % 09.07.2021

            % 08.02.2021, Tobias: first draft (modified version to work with AppDesigner)
            % 09.07.2021, Tobias: added comment in EDAANALYSIS struct

            % Save data form comment field into EDAANALYSIS struct
            app.EDAANALYSIS.comment = app.CommentsTextArea.Value;

            % Prepare variable
            EDAANALYSIS = app.EDAANALYSIS;

            % Save the data (without handles)
            uisave('EDAANALYSIS',[app.EDAANALYSIS.edaFilePath app.EDAANALYSIS.edaFileName(1:end-4) '_EDA_Result.mat'])
        end

        % Button pushed function: ExportEDAExcelMATLABButton
        function export2Excel(app, event)
            % This function exports the computed eda data to Excel

            % Tobias Otto
            % 1.1
            % 16.02.2021

            % 08.02.2021, Tobias: first draft (modified version to work with AppDesigner)
            % 16.02.2021, Tobias: more advanced input for time slots

            % Define output file
            [f, d] = uiputfile([app.EDAANALYSIS.edaFilePath app.EDAANALYSIS.edaFileName(1:end-3) 'xlsx'], 'Export EDA result to Excel');

            % Open another window for start and stop time(s)
            opts.Interpreter    = 'tex';
            opts.Resize         = 'on';
            text = sprintf('\nEnter one or more start and stop times in seconds separated by a colon in a comma separated list or leave the field empty to select the whole trial.\n');
            text = [text sprintf('To create Excel files with a specified time range and the whole trial use the keyword "all"\n\n')];
            text = [text sprintf('E.g.: 1:2, 2.5:3.5, 0:1.5   --> creates three Excel files with the time range 1s to 2s and 2.5s to 3.5s and 0s to 1.5s\n')];
            text = [text sprintf('E.g.: 5.5:7, all   --> creates two Excel files with the time range 5.5s to 7s and the whole trial\n')];
            text = [text sprintf('E.g.: click OK   --> creates one Excel file with the whole trial\n')];
            % Finally open input dialog
            in = inputdlg({['\fontsize{10}' text]}, 'Coose time window(s) for exporting the EDA data to Excel', [1 150], {''}, opts);

            % Get single entries
            sep = regexp(in{1}, ',', 'Split');
            j   = 1;

            for i=1:length(sep)
                % Get start and stop times
                sep2 = regexp(sep{i}, ':', 'Split');

                % Copy to new variable
                if(length(sep2)==2)
                    ignore      = 0;
                    tmpStart	= str2double(sep2{1});
                    tmpStop     = str2double(sep2{2});

                    % Check if we have valid numbers
                    if(~(isnumeric(tmpStart) && isnumeric(tmpStop)))
                        fid = errordlg(['One of the entered values is not a number! Input "' sep2{1} '" and "' sep2{2} '" are ignored.']);
                        waitfor(fid);
                        ignore = 1;
                    end

                    % Check if numbers are ascending
                    if(tmpStart >= tmpStop)
                        fid = errordlg(['The start time is greater than or equal to the end time! Input "' sep2{1} '" and "' sep2{2} '" are ignored.']);
                        waitfor(fid);
                        ignore = 1;
                    end

                    % Finally copy to vector
                    if(ignore == 0)
                        timStart(j) = tmpStart;
                        timStop(j)  = tmpStop;
                        j           = j+1;
                    end
                elseif(length(sep2) == 1)
                    % Check if key word for whole trial is found
                    if(contains(sep2{1}, 'all'))
                        timStart(j) = nan;  % Special value to mark whole trial
                        timStop(j)  = nan;  % Special value to mark whole trial
                        j           = j+1;
                    elseif(isempty(sep2{1}))
                        timStart(j) = nan;  % Special value to mark whole trial
                        timStop(j)  = nan;  % Special value to mark whole trial
                        j           = j+1;
                    else
                        fid = errordlg(['The keyword "all" for whole trial is not found in this statement! Input "' sep2{1} '" is ignored.']);
                        waitfor(fid);
                    end
                else
                    fid = errordlg(['There was a general error on input number ' num2str(i) '. This input is ignored!']);
                    waitfor(fid);
                end
            end

            % Call script to export data
            for i=1:length(timStart)
                if(isnan(timStart(i)))
                    fn = [f(1:end-5) '__all.xlsx'];
                    app.exportEDA2Excel(fn, d, [], []);
                else
                    fn = [f(1:end-5) '__' num2str(timStart(i)) 's-' num2str(timStop(i)) 's.xlsx'];
                    app.exportEDA2Excel(fn, d, timStart(i), timStop(i));
                end
            end
        end

        % Button pushed function: LoadEDAdataButton
        function loadEDAData(app, event)
            % Saves the EDAANALYSIS struct as MATLAB file

            % Tobias Otto
            % 1.2
            % 10.12.2021

            % 08.02.2021, Tobias: first draft  (modified version to work with AppDesigner)
            % 09.07.2021, Tobias: added comment and checking for minYVal and timeWinStart in old data
            % 10.12.2021, Tobias: added recording system in struct for old data

            % Since user wants so load new data remove old data (similar to
            % loadRawData)

            % If this function is called all available user data can be deleted.
            app.initEDAData;

            % Reset application settings as well
            app.axesMain.XLim   = [0 1];
            app.axesMain.XTick  = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axesMain.YLim   = [0 1];
            app.axesMain.YTick  = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];

            %% Get filenames and load data
            [edaFile, p] = uigetfile('*.mat', 'Select EDA file');
            if(p == 0)
                return
            else
                edaFilePath = p;
                load([p,edaFile], 'EDAANALYSIS')

                % Check if data have to converted to new format
                if(exist('EDAANALYSIS', 'var') == 0)
                    % Load data in old format
                    load([p,edaFile], 'out');
                    % Mark to import necessary data form old struct
                    convert = 1;
                else
                    % Copy data to app struct
                    app.EDAANALYSIS = EDAANALYSIS;
                    % Nothing to do
                    convert = 0;
                end
            end

            %% Convert data if necessary
            if(convert == 1)
                % Copy data to new struct
                app.EDAANALYSIS.configFileName          = out.configFileName;
                app.EDAANALYSIS.eda                     = out.eda;
                app.EDAANALYSIS.edaAdjusted             = out.edaAdjusted;
                app.EDAANALYSIS.edaData                 = out.edaData;
                app.EDAANALYSIS.edaFileName             = out.edaFileName;
                app.EDAANALYSIS.edaFilePath             = out.edaFilePath;
                app.EDAANALYSIS.edaMaskWindow           = out.edaMaskWindow;
                app.EDAANALYSIS.edaMaxData              = out.edaMaxData;
                app.EDAANALYSIS.edaMaxTime              = out.edaMaxTime;
                app.EDAANALYSIS.edaMaxVal               = out.edaMaxVal;
                app.EDAANALYSIS.edaMinData              = out.edaMinData;
                app.EDAANALYSIS.edaMinTime              = out.edaMinTime;
                app.EDAANALYSIS.edaRes                  = out.edaRes;
                app.EDAANALYSIS.edaTime                 = out.edaTime;
                app.EDAANALYSIS.edaTimeRes              = out.edaTimeRes;
                app.EDAANALYSIS.filterNumerator         = app.computeFilterSettings(out.sampRate);
                app.EDAANALYSIS.globalTrialStartTime    = out.globalTrialStartTime;
                app.EDAANALYSIS.globalTrialStopTime     = out.globalTrialStopTime;
                app.EDAANALYSIS.i                       = out.i;
                app.EDAANALYSIS.numTrials               = out.numTrials;
                app.EDAANALYSIS.sPos                    = out.sPos;
                app.EDAANALYSIS.sPosTime                = out.sPosTime;
                app.EDAANALYSIS.sPosTimeStimOnset       = out.sPosTimeStimOnset;
                app.EDAANALYSIS.sampRate                = out.sampRate;
                app.EDAANALYSIS.startTime               = out.startTime;
                app.EDAANALYSIS.stimOnset               = out.stimOnset;
                app.EDAANALYSIS.stimPresTime            = out.stimPresTime;
                app.EDAANALYSIS.stopTime                = out.stopTime;
                app.EDAANALYSIS.timeWindowStart         = out.timeWindowStart;
                app.EDAANALYSIS.timeWindowStop          = out.timeWindowStop;
                app.EDAANALYSIS.trialData               = out.trialData;
                app.EDAANALYSIS.trialSep                = out.trialSep;
                app.EDAANALYSIS.trialStart              = out.trialStart;
                app.EDAANALYSIS.trialStop               = out.trialStop;
                app.EDAANALYSIS.trialTime               = out.trialTime;
                app.EDAANALYSIS.xlabel                  = out.xlabel;
                app.EDAANALYSIS.yLimFactor              = out.yLimFactor;
                app.EDAANALYSIS.yRangeMarkerFactor      = out.yRangeMarkerFactor;
                app.EDAANALYSIS.ylabel                  = out.ylabel;
            end

            %% In older versions some entries are missing
            % Add comments if missing
            if(~isfield(app.EDAANALYSIS, 'comment'))
                app.EDAANALYSIS.comment = {''};
            end

            % Add minYVal
            if(~isfield(app.EDAANALYSIS.edaRes(1), 'minYVal'))
                for i=1:length(app.EDAANALYSIS.edaRes)
                    app.EDAANALYSIS.edaRes(i).minYVal = 0.01;
                end
            end

            % Add timeWinStart
            if(~isfield(app.EDAANALYSIS.edaRes(1), 'timeWinStart'))
                for i=1:length(app.EDAANALYSIS.edaRes)
                    app.EDAANALYSIS.edaRes(i).timeWinStart = 0;
                end
            end

            % Add recordingSystem
            if(~isfield(app.EDAANALYSIS.edaRes(1), 'recordingSystem'))
                for i=1:length(app.EDAANALYSIS.edaRes)
                    app.EDAANALYSIS.edaRes(i).recordingSystem = 'N/A';
                end
            end

            %% Add user info
            app.labelFileName.Text = [p,edaFile];

            %% Plot first EDA
            app.plotEDAData;
            % Add plotting comments here since we don't touch the fields somewhere else, except
            % during saving
            app.CommentsTextArea.Value = app.EDAANALYSIS.comment;
        end

        % Button pushed function: PreviousTrialButton
        function previousTrial(app, event)
            % Plots the previous trial

            % Tobias Otto
            % 1.0
            % 09.02.2021

            % 09.02.2021, Tobias: first draft  (modified version to work with AppDesigner)

            if(app.EDAANALYSIS.i > 1)
                app.EDAANALYSIS.i = app.EDAANALYSIS.i - 1;

                % Plot only! no new computation
                app.plotEDAData;
            end
        end

        % Button pushed function: NextTrialButton
        function nextTrial(app, event)
            % Plots the next trial

            % Tobias Otto
            % 1.0
            % 09.02.2021

            % 09.02.2021, Tobias: first draft  (modified version to work with AppDesigner)

            % Get max number of trials
            if(~isempty(app.EDAANALYSIS.trialSep))
                len = length(app.EDAANALYSIS.trialSep)-1;
            else
                len = length(app.EDAANALYSIS.trialStart);
            end

            if(app.EDAANALYSIS.i < len)
                app.EDAANALYSIS.i = app.EDAANALYSIS.i + 1;

                % Compute new entry if no computation exists
                if(app.EDAANALYSIS.i > length(app.EDAANALYSIS.edaRes))
                    % Compute data
                    app.computeData2Plot;
                    % Plot data
                    app.plotEDAData;
                else
                    app.plotEDAData;
                end
            end
        end

        % Button pushed function: ExitButton
        function exitApplication(app, event)
            % Tobias Otto
            % 1.0
            % 01.03.2021

            % 01.03.2021, Tobias: first draft

            delete(app);
        end

        % Value changed function: SplitSelectedDropDown
        function splitSelectedEDAVersion(app, event)
            % This function is called if the drop down menue for split selected is changed to old

            % Tobias Otto
            % 1.0
            % 09.07.2021

            % 09.07.2021, Tobias: first draft

            % Get data and copy to EDAANALYSIS struct
            app.EDAANALYSIS.splitSelectedEDAVersion = app.SplitSelectedDropDown.Value;

        end

        % Button pushed function: Export2BIDSButton
        function export2BIDSButtonPushed(app, event)
            % This function is called to export the raw EDA data into the BIDS format
            % All necessary folders adre created automatically

            % Tobias Otto
            % 1.0
            % 12.01.2022

            % 12.01.2022, Tobias: first draft
            % 20.04.2022, Tobias: keeping + and - in sPos names and in json files 

            %% Check if bids data are available!
            if(isempty(app.EDAANALYSIS.bids))
                opts = struct('WindowStyle','modal', 'Interpreter','tex');
                h = errordlg('\fontsize{11} The Trial Definition file is missing the required information for the BIDS export. Please update the file and try again. The export is aborted.', 'BIDS Export Error', opts);
                waitfor(h);
                return
            end
            
            %% Start collecting all information to generate the necessary BIDS information
            % From here in we assume that all information are entered correctly
            % No checks anymore

            % Select folder for files
            selPath = uigetdir(pwd, 'Select a folder to store the BIDS data there.');
            if(any(selPath == false))
                selPath = pwd;
            end

            %% Create general filename and folder structure AND create folder structure
            if(isempty(app.EDAANALYSIS.bids.subjectName))
                app.EDAANALYSIS.bids.subjectName = 'defaultSubjectName';
            end
            subject = essbids_replacePlaceholders(app, app.EDAANALYSIS.bids.subjectName);
            %subject = app.EDAANALYSIS.bids.subjectName;

            if(~isnumeric(app.EDAANALYSIS.bids.session))
                app.EDAANALYSIS.bids.session = str2double(app.EDAANALYSIS.bids.session);
            end
            session = app.EDAANALYSIS.bids.session;

            if(isempty(app.EDAANALYSIS.bids.task))
                app.EDAANALYSIS.bids.task = 'NoTaskSpecified';
            end
            task = essbids_replacePlaceholders(app, app.EDAANALYSIS.bids.task);

            if(~isnumeric(app.EDAANALYSIS.bids.run))
                app.EDAANALYSIS.bids.run = str2double(app.EDAANALYSIS.bids.run);
            end
            run = app.EDAANALYSIS.bids.run;
            
            filename = ['sub-' subject '_' 'ses-' num2str(session) '_' 'task-' task '_' 'run-' num2str(run) '_'];

            % Create folder names and folders (if necessary)
            folderBase = [selPath filesep 'rawdata' filesep];
            if(run == 1)
                folder = [folderBase 'sub-' subject filesep 'ses-' num2str(session) filesep 'beh'];
            else
                folder = [folderBase 'sub-' subject filesep 'ses-' num2str(session) filesep 'run-' num2str(run) filesep 'beh'];
            end


            if(~exist(folder, 'file'))
                mkdir(folder);
            end
    
            % Final filename
            filename = [folder filesep filename];

            %% Create physio tsv
            app.essbids_writeTsv([filename 'physio.tsv.gz'], array2table(app.EDAANALYSIS.eda), 1);

            %% Create json
            jsonFilename = [filename 'physio.json'];
            
            jeda.SamplingFrequency  = app.EDAANALYSIS.sampRate;    % Sampling frequency in Hz of all columns in the file.
            jeda.StartTime          = 0;                            % Start time in seconds in relation to the start of acquisition of the first data sample in the corresponding neural dataset (negative values are allowed).
            jeda.Columns            = {'skinconductance'};          % Names of columns in file.
            
            % Optional infos from here on
            jeda.skinconductance.Units  = 'microsiemens';
            jeda.NumTrials              = app.EDAANALYSIS.numTrials;
            jeda.Manufacturer           = app.EDAANALYSIS.recordingSystem;
            
            % Finally write json file
            app.essbids_writeJson(jsonFilename,jeda)

            %% Get additional information for events tsv
            % Add information to triggers: Duration of single stimulus presentations (for older
            % trialDef files we have to enter this value manually) :(
            if(isfield(app.EDAANALYSIS.bids, 'stimDuration') == 0)
                defInput{1}         = 'NaN';
                opts.Interpreter    = 'tex';
                dims                = [1 160];
                dlg{1}              = '\fontsize{10} Please enter the presentation time in s for marker \bfstimulus|trialStart \rm(stimOnset in trialDefinition file):';
                
                % Finally start dialog and get answers
                userInfo = inputdlg(dlg, 'Define duration of events in BIDS', dims, defInput, opts);
            else
                userInfo{1} = num2str(app.EDAANALYSIS.bids.stimDuration);   % Necessary, because userInfo could be a inputdlg result
            end

            %% Get data from the trial def file
            % Create struct entries for every trial
            entries     = length(app.EDAANALYSIS.sPos) + 1; % we always have a stimulus onset
            finalDat    = [];
            finalNam    = {};
            finalDur    = [];
            
            for i=1:app.EDAANALYSIS.numTrials
                % Init variables
                dat = nan(1, entries);
                nam = cell(1, entries);
                dur = nan(1, entries);
            
                % Stimulus onset
                dat(1) = app.EDAANALYSIS.stimOnset(i);
                nam{1} = 'Stimulus/TrialStart';
                dur(1) = str2double(userInfo{1});
            
                % all sPos entries
                for j=1:length(app.EDAANALYSIS.sPos)
                    dat(j+1) = app.EDAANALYSIS.sPos{j}.time(i);
                    nam{j+1} = app.EDAANALYSIS.sPos{j}.name;
                    dur(j+1) = app.EDAANALYSIS.sPos{j}.duration;
                end
            
                % Delete invalid antries --> -1
                pos         = find(dat == -1);
                dat(pos)    = [];
                nam(pos)    = [];
                dur(pos)    = [];
            
                % Sort data of necessary
                [~, pos] = sort(dat);
                dat = dat(pos);
                nam = nam(pos);
                dur = dur(pos);
            
                % Copy final data to result vector
                finalDat    = [finalDat dat];
                finalNam    = [finalNam nam];
                finalDur    = [finalDur dur];
            end
            
            % Create table for trigger file
            finalTable = cell2table([num2cell(finalDat'), num2cell(finalDur'), finalNam']);
            
            % Change header names
            finalTable.Properties.VariableNames{1} = 'onset';
            finalTable.Properties.VariableNames{2} = 'duration';
            finalTable.Properties.VariableNames{3} = 'trial_type';

            %% Write tsv file
            app.essbids_writeTsv([filename 'events.tsv'], finalTable);

            %% Write json file to explain events more properly
            jev.onset.description   = 'Event to indicate the trial start or the stimulus onset.';
            jev.onset.units         = 'Seconds';
            jev.duration.description= 'Duration of the given event in the experiment';
            jev.duration.units      = 'Seconds';

            for j=1:length(app.EDAANALYSIS.sPos)
                tmpName                     = app.essbids_replacePlaceholders(app.EDAANALYSIS.sPos{j}.name, 3); % Rename +,- to plus,minus and remove whitespace
                jev.(tmpName).description   = app.EDAANALYSIS.sPos{j}.description;
                jev.(tmpName).units         = 'Seconds';
            end

            % Finally write json file
            app.essbids_writeJson([filename 'events.json'], jev);

            %% Write additional json file to describe the task in more detail
            jdesc.taskDescription   = app.EDAANALYSIS.bids.taskDescription;
            jdesc.taskName          = app.EDAANALYSIS.bids.task;
            jdesc.subjectName       = app.EDAANALYSIS.bids.subjectName;
            jdesc.session           = app.EDAANALYSIS.bids.session;
            jdesc.run               = app.EDAANALYSIS.bids.run;
            jdesc.institutionName   = app.EDAANALYSIS.bids.info;
            jdesc.contact           = app.EDAANALYSIS.bids.contact;

            app.essbids_writeJson([filename 'description.json'], jdesc);

            %% Finally write a .bidsignore file
            % This file is needed to ignore the SFB 1280 specific meta.json file and the json file
            % containing additional information to the task (*_description.json)
            writecell({'**/meta.json'; '**/*_description.json'}, [folderBase 'bidsignore.txt']);
            movefile([folderBase 'bidsignore.txt'], [folderBase '.bidsignore'])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axesMain = uiaxes(app.UIFigure);
            title(app.axesMain, '')
            xlabel(app.axesMain, 'X')
            ylabel(app.axesMain, 'Y')
            app.labelFileName = uilabel(app.UIFigure);
            app.labelFileName.Text = 'No data loaded. Please select "Load raw data" or "Load EDA data" to start your analysis.';
            app.labelTrialNumber = uilabel(app.UIFigure);
            app.labelTrialNumber.Text = 'Trial number';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersforEDAAnalysisDerivationMethodTab = uitab(app.TabGroup);
            app.ParametersforEDAAnalysisDerivationMethodTab.Title = 'Parameters for EDA Analysis - Derivation Method';
            app.EnablestartandendtimeforalltrialsCheckBox = uicheckbox(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.EnablestartandendtimeforalltrialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableGlobalStartEndTime, true);
            app.EnablestartandendtimeforalltrialsCheckBox.Text = 'Enable start and end time for all trials';
            app.MinwidthinsecondstodefinestartEditField_2Label = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.MinwidthinsecondstodefinestartEditField_2Label.Text = 'Min width in seconds to define start';
            app.MinwidthinsecondstodefinestartEditField = uieditfield(app.ParametersforEDAAnalysisDerivationMethodTab, 'numeric');
            app.MinwidthinsecondstodefinestartEditField.ValueChangedFcn = createCallbackFcn(app, @minWidthInSecondsCopy, true);
            app.MinwidthinsecondstodefinestartEditField.Value = 0.5;
            app.MinheightinStodefinestartEditFieldLabel = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.MinheightinStodefinestartEditFieldLabel.Text = 'Min height in µS to define start';
            app.MinheightinStodefinestartEditField = uieditfield(app.ParametersforEDAAnalysisDerivationMethodTab, 'numeric');
            app.MinheightinStodefinestartEditField.ValueChangedFcn = createCallbackFcn(app, @minHeighInVoltCopy, true);
            app.MinheightinStodefinestartEditField.Value = 0.01;
            app.MinpeakwidthinsecondsEditField_2Label = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.MinpeakwidthinsecondsEditField_2Label.Text = 'Min peak width in seconds';
            app.MinpeakwidthinsecondsEditField = uieditfield(app.ParametersforEDAAnalysisDerivationMethodTab, 'numeric');
            app.MinpeakwidthinsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @minPeakWidthCopy, true);
            app.MinpeakwidthinsecondsEditField.Value = 0.1;
            app.StartoftimewindowinsecondsEditField_2Label = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.StartoftimewindowinsecondsEditField_2Label.Text = 'Start of time window in seconds';
            app.StartoftimewindowinsecondsEditField = uieditfield(app.ParametersforEDAAnalysisDerivationMethodTab, 'numeric');
            app.StartoftimewindowinsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @startOfTimeWindowCopy, true);
            app.EndoftimewindowinsecondsEditField_2Label = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.EndoftimewindowinsecondsEditField_2Label.Text = 'End of time window in seconds';
            app.EndoftimewindowinsecondsEditField = uieditfield(app.ParametersforEDAAnalysisDerivationMethodTab, 'numeric');
            app.EndoftimewindowinsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @endOfTimeWindowCopy, true);
            app.EndoftimewindowinsecondsEditField.Value = 5;
            app.CommentsTextAreaLabel = uilabel(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.ParametersforEDAAnalysisDerivationMethodTab);
            app.ParametersforEDAAnalysisPeaksMethodTab = uitab(app.TabGroup);
            app.ParametersforEDAAnalysisPeaksMethodTab.Title = 'Parameters for EDA Analysis - Peaks Method';
            app.EnablestartandendtimeforalltrialsCheckBox_2 = uicheckbox(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.EnablestartandendtimeforalltrialsCheckBox_2.Enable = 'off';
            app.EnablestartandendtimeforalltrialsCheckBox_2.Text = 'Enable start and end time for all trials';
            app.MinwidthinsecondstodefinestartEditField_2Label_2 = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.MinwidthinsecondstodefinestartEditField_2Label_2.Enable = 'off';
            app.MinwidthinsecondstodefinestartEditField_2Label_2.Text = 'Min width in seconds to define start';
            app.MinwidthinsecondstodefinestartEditField_2 = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.MinwidthinsecondstodefinestartEditField_2.Editable = 'off';
            app.MinwidthinsecondstodefinestartEditField_2.Enable = 'off';
            app.MinwidthinsecondstodefinestartEditField_2.Value = 0.5;
            app.MinheightinStodefinestartEditField_2Label = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.MinheightinStodefinestartEditField_2Label.Enable = 'off';
            app.MinheightinStodefinestartEditField_2Label.Text = 'Min height in µS to define start';
            app.MinheightinStodefinestartEditField_2 = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.MinheightinStodefinestartEditField_2.Editable = 'off';
            app.MinheightinStodefinestartEditField_2.Enable = 'off';
            app.MinheightinStodefinestartEditField_2.Value = 0.01;
            app.MinpeakwidthinsecondsEditField_2Label_2 = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.MinpeakwidthinsecondsEditField_2Label_2.Enable = 'off';
            app.MinpeakwidthinsecondsEditField_2Label_2.Text = 'Min peak width in seconds';
            app.MinpeakwidthinsecondsEditField_2 = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.MinpeakwidthinsecondsEditField_2.Editable = 'off';
            app.MinpeakwidthinsecondsEditField_2.Enable = 'off';
            app.MinpeakwidthinsecondsEditField_2.Value = 0.1;
            app.StartoftimewindowinsecondsEditField_2Label_2 = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.StartoftimewindowinsecondsEditField_2Label_2.Enable = 'off';
            app.StartoftimewindowinsecondsEditField_2Label_2.Text = 'Start of time window in seconds';
            app.StartoftimewindowinsecondsEditField_2 = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.StartoftimewindowinsecondsEditField_2.Editable = 'off';
            app.StartoftimewindowinsecondsEditField_2.Enable = 'off';
            app.EndoftimewindowinsecondsEditField_2Label_2 = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.EndoftimewindowinsecondsEditField_2Label_2.Enable = 'off';
            app.EndoftimewindowinsecondsEditField_2Label_2.Text = 'End of time window in seconds';
            app.EndoftimewindowinsecondsEditField_2 = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.EndoftimewindowinsecondsEditField_2.Editable = 'off';
            app.EndoftimewindowinsecondsEditField_2.Enable = 'off';
            app.EndoftimewindowinsecondsEditField_2.Value = 5;
            app.PleasechangethesesettingsintheDerivationMethodtabifneededLabel = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.PleasechangethesesettingsintheDerivationMethodtabifneededLabel.Text = 'Please change these settings in the Derivation Method tab if needed.';
            app.EnableProminenceCheckBox = uicheckbox(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.EnableProminenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @enableProminence, true);
            app.EnableProminenceCheckBox.Text = 'Enable Prominence';
            app.ProminenceMinLabel = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.ProminenceMinLabel.Enable = 'off';
            app.ProminenceMinLabel.Text = 'Prominence Min';
            app.ProminenceMinEditField = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.ProminenceMinEditField.Editable = 'off';
            app.ProminenceMinEditField.Enable = 'off';
            app.ProminenceMinEditField.Value = 0.1;
            app.ProminenceMaxLabel = uilabel(app.ParametersforEDAAnalysisPeaksMethodTab);
            app.ProminenceMaxLabel.Enable = 'off';
            app.ProminenceMaxLabel.Text = 'Prominence Max';
            app.ProminenceMaxEditField = uieditfield(app.ParametersforEDAAnalysisPeaksMethodTab, 'numeric');
            app.ProminenceMaxEditField.Editable = 'off';
            app.ProminenceMaxEditField.Enable = 'off';
            app.ProminenceMaxEditField.Value = 0.2;
            app.LicenseAcknowledgmentTab = uitab(app.TabGroup);
            app.LicenseAcknowledgmentTab.Title = 'License + Acknowledgment';
            app.TextArea = uitextarea(app.LicenseAcknowledgmentTab);
            app.TextArea.Value = {'This work is licensed under a Creative Commons Attribution-ShareAlike 4.0 International License.'; 'https://creativecommons.org/licenses/by-sa/4.0/'; ''; 'The EDA App was written by:'; 'Tobias Otto, tobias.otto@rub.de, Ruhr University Bochum, Faculty of Psychology, Cognitive Psychology, Bochum, Germany'; ''; 'The EDA App uses the essbids toolbox to export data into the BIDS format. The essbids toolbox was written by:'; 'Thomas M. Ernst, thomas.m.ernst@uni-due.de, University Hospital Essen, Essen, Germany'};
            app.globalStartTime_Label = uilabel(app.UIFigure);
            app.globalStartTime_Label.Text = '0';
            app.globalStopTime_label = uilabel(app.UIFigure);
            app.globalStopTime_label.Text = '1';
            app.RecomputeEDA_Button = uibutton(app.UIFigure, 'push');
            app.RecomputeEDA_Button.ButtonPushedFcn = createCallbackFcn(app, @recomputeEDA, true);
            app.RecomputeEDA_Button.Text = 'Recompute EDA';
            app.yAxisPlus_Button = uibutton(app.UIFigure, 'push');
            app.yAxisPlus_Button.ButtonPushedFcn = createCallbackFcn(app, @yAxisPlus, true);
            app.yAxisPlus_Button.Text = '+';
            app.yAxisMinus_Button = uibutton(app.UIFigure, 'push');
            app.yAxisMinus_Button.ButtonPushedFcn = createCallbackFcn(app, @yAxisMinus, true);
            app.yAxisMinus_Button.Text = '-';
            app.ChangescaleofYaxisLabel = uilabel(app.UIFigure);
            app.ChangescaleofYaxisLabel.Text = 'Change scale of Y axis';
            app.ComputedEDAPanel = uipanel(app.UIFigure);
            app.ComputedEDAPanel.Title = 'Computed EDA';
            app.DeleteselectedButton = uibutton(app.ComputedEDAPanel, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @deleteSelectedEDA, true);
            app.DeleteselectedButton.Text = 'Delete selected';
            app.Label = uilabel(app.ComputedEDAPanel);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.ComputedEDAPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.Label_2 = uilabel(app.ComputedEDAPanel);
            app.Label_2.Text = 'Time in s with reference to stimulus onset and trial start';
            app.MergewithpreviousButton = uibutton(app.ComputedEDAPanel, 'push');
            app.MergewithpreviousButton.ButtonPushedFcn = createCallbackFcn(app, @mergeWithPreviousEDA, true);
            app.MergewithpreviousButton.Text = 'Merge with previous';
            app.SplitselectedButton = uibutton(app.ComputedEDAPanel, 'push');
            app.SplitselectedButton.ButtonPushedFcn = createCallbackFcn(app, @splitSelectedEDA, true);
            app.SplitselectedButton.Text = 'Split selected';
            app.SplitSelectedDropDown = uidropdown(app.ComputedEDAPanel);
            app.SplitSelectedDropDown.Items = {'New', 'Old'};
            app.SplitSelectedDropDown.ValueChangedFcn = createCallbackFcn(app, @splitSelectedEDAVersion, true);
            app.SplitSelectedDropDown.Value = 'New';
            app.PreviousTrialButton = uibutton(app.UIFigure, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @previousTrial, true);
            app.PreviousTrialButton.Text = 'Previous Trial';
            app.NextTrialButton = uibutton(app.UIFigure, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @nextTrial, true);
            app.NextTrialButton.Text = 'Next Trial';
            app.VersionNumber = uilabel(app.UIFigure);
            app.VersionNumber.Text = 'Version 5.7, 21.04.2022 tobias.otto@rub.de';
            app.FilehandlingPanel = uipanel(app.UIFigure);
            app.FilehandlingPanel.Title = 'File handling';
            app.buttonLoadRawData = uibutton(app.FilehandlingPanel, 'push');
            app.buttonLoadRawData.ButtonPushedFcn = createCallbackFcn(app, @loadRawData, true);
            app.buttonLoadRawData.Text = 'Load raw data';
            app.LoadEDAdataButton = uibutton(app.FilehandlingPanel, 'push');
            app.LoadEDAdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadEDAData, true);
            app.LoadEDAdataButton.Text = 'Load EDA data';
            app.SaveEDAButton = uibutton(app.FilehandlingPanel, 'push');
            app.SaveEDAButton.ButtonPushedFcn = createCallbackFcn(app, @saveEDA, true);
            app.SaveEDAButton.Text = 'Save EDA';
            app.ExportEDAExcelMATLABButton = uibutton(app.FilehandlingPanel, 'push');
            app.ExportEDAExcelMATLABButton.ButtonPushedFcn = createCallbackFcn(app, @export2Excel, true);
            app.ExportEDAExcelMATLABButton.Text = 'Export EDA (Excel/MATLAB)';
            app.Export2BIDSButton = uibutton(app.FilehandlingPanel, 'push');
            app.Export2BIDSButton.ButtonPushedFcn = createCallbackFcn(app, @export2BIDSButtonPushed, true);
            app.Export2BIDSButton.Text = 'Export 2 BIDS';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exitApplication, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cog-logo.png';
            app.wwwcogpsyrubdeLabel = uilabel(app.UIFigure);
            app.wwwcogpsyrubdeLabel.Text = 'www.cog.psy.rub.de';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDAAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end